classdef OCTAVA_2p0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OCTAVAUIFigure                  matlab.ui.Figure
        SaveMenu                        matlab.ui.container.Menu
        saveBinaryImageMenu             matlab.ui.container.Menu
        saveSessionDataMenu             matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        ClearsessiondataMenu            matlab.ui.container.Menu
        ClearplotsMenu                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ShowOCTAVAhelpMenu              matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        PrebinaryCheckBox               matlab.ui.control.CheckBox
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        sigmaminSpinner                 matlab.ui.control.Spinner
        DisplayoverlayCheckBox          matlab.ui.control.CheckBox
        savefileLabel_single            matlab.ui.control.Label
        BatchProcessingPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        SegmentFAZCheckBox              matlab.ui.control.CheckBox
        VariableimgsizeCheckBox         matlab.ui.control.CheckBox
        RegionanalysisCheckBox          matlab.ui.control.CheckBox
        ProcessFolderOrList             matlab.ui.control.DropDown
        BatchResizeVal                  matlab.ui.control.NumericEditField
        BatchresizeCheckBox             matlab.ui.control.CheckBox
        Abort                           matlab.ui.control.StateButton
        ProgressLabel                   matlab.ui.control.Label
        StartbatchprocessButton         matlab.ui.control.Button
        SavebinaryCheckBox              matlab.ui.control.CheckBox
        FiletypeDropDownLabel           matlab.ui.control.Label
        FiletypeDropDown                matlab.ui.control.DropDown
        bachfileLabel                   matlab.ui.control.Label
        RawdatafolderButton             matlab.ui.control.Button
        MetricsPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        MeantortuosityLabel             matlab.ui.control.Label
        FractaldimensionLabel           matlab.ui.control.Label
        TotalvessellengthLabel          matlab.ui.control.Label
        BranchpointdensityLabel         matlab.ui.control.Label
        MediandiameterLabel             matlab.ui.control.Label
        MeandiameterLabel               matlab.ui.control.Label
        VessellengthdensityLabel        matlab.ui.control.Label
        VesselareadensityLabel          matlab.ui.control.Label
        RetinalAnalysisPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        FreeFAZButton                   matlab.ui.control.Button
        PointsFAZButton                 matlab.ui.control.Button
        RapidFAZButton                  matlab.ui.control.Button
        TuneFAZsegmentationCheckBox     matlab.ui.control.CheckBox
        RapidFAZstringencyfactorSliderLabel  matlab.ui.control.Label
        RapidFAZstringencyfactorSlider  matlab.ui.control.Slider
        GridLayout6                     matlab.ui.container.GridLayout
        GridType                        matlab.ui.control.DropDown
        MeasureFAZButton                matlab.ui.control.Button
        RegionalanalysisButton          matlab.ui.control.Button
        RL                              matlab.ui.control.DropDown
        binsizeDiams                    matlab.ui.control.Spinner
        BinsizeforhistogramsLabel       matlab.ui.control.Label
        ImagesizemmLabel                matlab.ui.control.Label
        SegmentationmethodDropDownLabel  matlab.ui.control.Label
        RealImageSizemm                 matlab.ui.control.NumericEditField
        binsizeTort                     matlab.ui.control.Spinner
        binsizeLength                   matlab.ui.control.Spinner
        SelectSaveFolderButton_single   matlab.ui.control.Button
        TwigsizeSpinner                 matlab.ui.control.Spinner
        TwigsizeLabel                   matlab.ui.control.Label
        ATkernelsizeSpinner             matlab.ui.control.Spinner
        ATkernelsizeSpinnerLabel        matlab.ui.control.Label
        sigmamaxSpinner                 matlab.ui.control.Spinner
        FrangiBool                      matlab.ui.control.CheckBox
        kernelSize                      matlab.ui.control.Spinner
        MedfiltCheckBox                 matlab.ui.control.CheckBox
        SegmentationmethodDropDown      matlab.ui.control.DropDown
        ImagesubsetButton               matlab.ui.control.Button
        SkeletonizeButton               matlab.ui.control.Button
        InvertButton                    matlab.ui.control.Button
        BinarizeButton                  matlab.ui.control.Button
        ResizeButton                    matlab.ui.control.Button
        LoadimageButton                 matlab.ui.control.Button
        ReadyLamp                       matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        ReadyLabel                      matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        OCTAVALabel                     matlab.ui.control.Label
        inputImageAxes                  matlab.ui.control.UIAxes
        BinaryImageAxes                 matlab.ui.control.UIAxes
        SkelImageAxes                   matlab.ui.control.UIAxes
        OverlayImageAxes                matlab.ui.control.UIAxes
        DiamsAxes                       matlab.ui.control.UIAxes
        LengthsAxes                     matlab.ui.control.UIAxes
        TortsAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %This section initialzes global variables
        homedir %Home directory of OCTAVA app
        inputImage % Input image for processing
        fullImSizePix %Keeps track of the size of the full image so that pix/mm isn't changed when subset is collected.
        BinarizationMethod % Selected Binarization Method to use
        BinaryImage % Binarized Image
        bin_overlay %Colormap for image overlays
        FileLabelName %Name of filebeing processed
        SaveDir %Path to results with folders for each image
        SaveFolder %Results folder for the current image
        skelReady %Boolean to say the software is ready to run skeletonization
        metrics %Structural array containing metric
        metrics_cumulative %collects all the metric for each dataset run in a given session
        metrics_counter %Keeps count of number of datasets processed in a session
        lastPath %keeps track of the last folder open when selecting save files
        imgShape %notes if image is square or rectangular for area calculation
        imgSkel %Stores the skelton image for calculation of fractal dimension
        imgOriginal %Stores orignial input image in case image cropping is applied to avoid boundary effects
        datanum %Stores a counter in case an image is run multiple times to avoid overwriting
        FAZ_ctr %Center point of the FAZ [x,y]
        Gnodes %nodes matrix from graph structure
        Glinks %links matrix from graph structure
        FAZ_metrics_cumulative  %For cumulative storing of FAZ metrics
        FAZ_metrics_counter %For keeping track of successive FAZ metrics caluclations
        RawFolder %Location of raw data for batch processing
        MatlabVer %keeps track if you have an older version of matlab
        Ffactor %FAZ scaling factor
        FAZmask %FAZ segmentation
        FAZtype %Saves whether the contour initialization was manual or rapid 
        binNum %Saves image number for binary mask 
        RAlabels %Labels for pages for regional analysis 
    end
    
    methods (Access = private)
        
        
        function init_session(app)
            %Reinitializes variables for saving session results
            app.metrics_counter = 0;
            app.metrics_cumulative = struct('name','filename','nodes',0,'VAD',0,'totalLength',0,'VLD_pct',0,'VLD_mminv',0,'MeanLength',0,'MedianLength',0,'varLengths',0,'skewLengths',0,'kurtLengths',0,'MeanDiam',0,'MedianDiam',0,'varDiams',0,'skewDiams',0,'kurtDiams',0,'BD',0,'FD',0,'mTort', 0,'varTort',0,'skewTort',0,'kurtTort',0, 'Frangi_sigma_max',0,'MedFilt_Kernel',0,'Binarization','Method','TwigSize',0);
            app.FAZ_metrics_counter = 0;

            if app.RegionanalysisCheckBox.Value
                init_session_retina(app)
            end
        end
        
        function init_session_retina(app)
            app.FAZ_metrics_counter = 0;
            app.FAZ_metrics_cumulative = struct('name', 'filename', 'Area', 0,'Centroid',0,'Circularity',0,'Eccentricity', 0,'MajoraxisLength',0,'MinoraxisLength',0,'Perimeter',0,'acircularity_index',0, 'axis_ratio',0, 'FD_300', 0);
        end


        function loadImage(app, Pathname, Filename)
            
            a = fullfile(Pathname, Filename);
            %Display a file browser for user to select image
            [~,~,ext] = fileparts(a);


            %Load the image file
            if strcmp(ext,'.mat')
                a = load(a);
                b = struct2cell(a);
                a = b{1};
            else
                a = imread(a);
                %i changed here a = im2uint8(a); %Testing!!
            end
            %Convert to grayscale
            if size(a,3) == 3
                a = rgb2gray(a);
            end
            app.inputImage = double(int16(squeeze(a(:,:,1))));

          
            inds = strfind(Filename,'.');
            app.FileLabelName = Filename(1:inds(end)-1);
            app.FileLabelName = erase(app.FileLabelName,'_');
            
            app.lastPath = Pathname;
            
            
            app.fullImSizePix = size(app.inputImage);
            app.imgShape = 'rect'; %Initialize image shape to rectange
            app.imgOriginal = app.inputImage; %Stores originial image to avoid boundary effect of binairaztion on cropped images
            app.FAZ_ctr = app.fullImSizePix/2; %FAZ center should be defaulted to the center of the image
            
            %Make directory for each image
            app.SaveFolder = fullfile(app.SaveDir,app.FileLabelName);
            if ~exist(app.SaveFolder, 'dir')
                mkdir(app.SaveFolder)
            end
            

            imwrite(app.inputImage, "5000input.png");
            imwrite(app.imgOriginal, "5000original.png");

        end
        
        function binarizeImage(app)
            %Load info
            Method = app.SegmentationmethodDropDown.Value; %Check selected binarization method
            
            
            % Make sure vessels are white and the rest is black
            [y] = max(app.inputImage(2:end,2:end));
            [~,cc] = max(y);
            [~,rr] = max(app.inputImage(2:end,cc));
            
            cc=cc+1;
            rr=rr+1;
            
            %%Apply Median filter if selected
            if app.MedfiltCheckBox.Value
                Im = medfilt2(app.inputImage,[app.kernelSize.Value app.kernelSize.Value]);
            else
                Im = app.inputImage;
            end
            
            % Binarize
            if app.FrangiBool.Value
                opts.sigmarange = sort([app.sigmaminSpinner.Value app.sigmamaxSpinner.Value]);
                opts.sigmastepsize=2;
                opts.correctionconst1=0.8;
                opts.correctionconst2=15;
                Im=frangi_2Dfilter(Im,opts);
            end
            
            if strcmp(Method,'Fuzzy means')
                %Implements Fuzzy thrshold binarization
                %Number of sets;
                nth=2;
                app.BinaryImage=fuzzy_thresholding(Im,nth,3)-1;
                
            elseif strcmp(Method,'Adaptive thresholding')
                %Implements adaptive threshold binarization
                app.BinaryImage= double(adaptivethresholding(Im,app.ATkernelsizeSpinner.Value));
                %Maybe make  the number here edjustable?
            else
                warndlg('Incorrect segmentation method') ;
            end
            
            app.BinaryImage(app.BinaryImage==app.BinaryImage(rr,cc)) = 255;
            app.BinaryImage(app.BinaryImage~=app.BinaryImage(rr,cc)) = 0;

            imwrite(app.BinaryImage,"5000binary.png");
            %imwrite(app.inputImage,"5000original.png");
        end
        

        function [sk2, TifFileName] = skeletonize(app, raw_img, bin_img, fol, disp)
            
            list = dir(fullfile(fol, '*_Skeleton*.tif'));
            imgnum = length(list);
            
            
            %generate the skeletonized image
            [Nx,Ny] = size(bin_img);
            L = logical(bin_img);
            sk2 = bwmorph(L,'skel',Inf); %skeletonizes
            
            %removes branches smaller than twig size
            sk2 = bwskel(sk2,'MinBranchLength',app.TwigsizeSpinner.Value);
            app.imgSkel = sk2;
            skel = logical(app.imgSkel);
            imwrite(uint8(skel)*255, 'skeleton_fd_test.png');
            
            if disp == 1
                %Save true skeleton 
                fig = figure;
                fig.Visible = 'off';
                imshow(imcomplement(sk2));
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Skeleton_' num2str(imgnum) '.tif']);
                export_fig(fig, TifFileName);
                close(fig)


                %Prepare display and save skeleton overlay image
                sk2 = bwmorph(sk2,'thicken');
                fig = figure;
                fig.Visible = 'off';
                figAxes = axes(fig);
                imagesc(raw_img*3/4+10, 'Parent', figAxes); %This doesn't work right now for the regional analysis, but it's just for display purposes... 
                hold(figAxes,'on');
                axis(figAxes,'equal',[0 Ny 0 Nx])
                colormap(fig,app.bin_overlay)
                imagesc(sk2*255, 'Parent', figAxes, 'AlphaData', .5);
                caxis(figAxes, [0 255])
                figAxes.YDir = 'reverse';
                axis(figAxes,'off')
                fig.Position = fig.Position.*[1 1 5 5];
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Skeleton Overlay_' num2str(imgnum) '.tif']);
                export_fig(fig, TifFileName);
                close(fig)
            end
            
            %Local thickness measurement
            D = bwdist(~L)*2; %Distance transform gives euclidean distance to nearest bg pixel
            %D is the closest I can get to the local thickness map so far, so
            %saving as such. Factor of 2 since distance transform gives radius
            if disp == 1
                pngFileName=fullfile(fol,[ app.FileLabelName  '_Local thickness_' num2str(imgnum) '.png']);
                D_output = D./max(max(D))*255;
                imwrite(D_output,hot,pngFileName);
            end
            
            %Network analysis
            %Based on code written by Philip Kollmannsberger
            if sum(sum(app.imgSkel)) == 0
                nodes = [];
                links = [];
            else
                [~,nodes,links] = Skel2Graph3D(app.imgSkel,app.TwigsizeSpinner.Value);


                %Calculate total length of network
                wl = sum(cellfun('length',{nodes.links}));
                
                %iterate to clean up network and get rid of excess nodes
                skel2 = Graph2Skel3D(nodes,links,Nx,Ny,1);
                [~,nodes,links] = Skel2Graph3D(skel2,0);
                wl_new = sum(cellfun('length',{nodes.links}));
                
                % iterate the same steps until network length changed by less than 0.5%
                while(wl_new~=wl)
                    
                    wl = wl_new;
                    
                    skel2 = Graph2Skel3D(nodes,links,Nx,Ny,1);
                    [~,nodes,links] = Skel2Graph3D(skel2,0);
                    
                    
                    wl_new = sum(cellfun('length',{nodes.links}));

                end
            end
            
            app.Gnodes = nodes;
            app.Glinks = links;
            
            %Add parameters to links structure that are required for
            %calculating metrics
            for i = 1:length(app.Glinks)
                [app.Glinks(i).x, app.Glinks(i).y, ~] = ind2sub([Nx,Ny,1],app.Glinks(i).point); %Adds the x and y coordinates for each point on the link
                d = diff([app.Glinks(i).y', app.Glinks(i).x']);
                app.Glinks(i).pixLength = sum(sqrt(sum(d.*d,2))); %Length of branch in pixels
                app.Glinks(i).end2endLength = sqrt((app.Glinks(i).x(end) - app.Glinks(i).x(1)).^2 + (app.Glinks(i).y(end) - app.Glinks(i).y(1)).^2); %arc length
                app.Glinks(i).tortuosity = app.Glinks(i).pixLength./ app.Glinks(i).end2endLength;
                app.Glinks(i).diameterMeasurements = D(app.Glinks(i).point); %Diameter measurement at each point within the link
                app.Glinks(i).diameter = mean(app.Glinks(i).diameterMeasurements); %Mean diameter for a given link
            end
            
            if disp == 1 && app.DisplayoverlayCheckBox.Value
                %display result
                %First, display the full graph structure in its own window
                fig = figure;
                fig.Visible = 'off'; %Visible is off because we are just using this to save the data for now
                figAxes = axes(fig);
                imagesc(raw_img, 'Parent', figAxes);
                hold(figAxes,'on');
                axis(figAxes,'equal',[0 Nx 0 Ny])
                colormap(fig,'gray')
                caxis(figAxes, [0 255])
                
                for i=1:length(nodes)
                    x1 = nodes(i).comx;
                    y1 = nodes(i).comy;
                    z1 = nodes(i).comz;


                    for j=1:length(nodes(i).links)    % draw all connections of each node
                        if(nodes(nodes(i).conn(j)).ep==1)
                            col='g'; % branches are black
                        else
                            col='y'; % links are red
                        end
                        if(nodes(i).ep==1)
                            col='g';
                        end
                        if(nodes(nodes(i).conn(j)).ep==1) && (nodes(i).ep==1)
                            col = 'b';
                        end
                        
                        
                        %                 % draw edges as lines using voxel positions
                        plot(figAxes,app.Glinks(nodes(i).links(j)).y, app.Glinks(nodes(i).links(j)).x,'Color',col,'LineWidth',2);
                        
                    end
                    
                    if(nodes(i).ep==1)
                        ncol = 'c';
                    else
                        ncol = 'm';
                        % draw all nodes as circles
                        plot3(figAxes,y1,x1,z1,'o','Markersize',4,...
                            'MarkerFaceColor',ncol,...
                            'Color','k');
                    end


                end
                axis image;axis off;
                drawnow;
                fig.Position = fig.Position.*[1 1 5 5];
                
                %Save as tif
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Overlay_' num2str(imgnum) '.tif']);
                export_fig(fig, TifFileName);
                close(fig)
                
            end

            skel = imread('skeleton_fd_test.png') > 0;
            app.imgSkel = skel;
            
            %fd_matlab = fractalDimension(app);
            %fprintf('Fractal Dimension (MATLAB): %.6f\n', fd_matlab);
            
        end
        
        function generateMetrics(app)
            app.metrics.name = app.FileLabelName;
            [Nx, ~] = size(app.inputImage); %Assuming square images for now - this is typically the case
            pix_to_mm = app.RealImageSizemm.Value/app.fullImSizePix(1);
            
            %Calcuate image area based on the actual image used after cropping
            if strcmp(app.imgShape,'rect')
                imgArea_mm = Nx*Nx*pix_to_mm^2; %This will now be correct for images that are rectangular
                imgArea_pix = Nx*Nx;
            else
                imgArea_mm = pi*(Nx/2)^2*pix_to_mm^2; %This will now be correct for images that are circular
                imgArea_pix = pi*(Nx/2)^2;
            end


            % Calulate metrics
            
            %Vessel area density
            app.metrics.VAD = round(sum(sum(app.BinaryImage))/255/imgArea_pix*100,2);
            
            %Total length, average length, VLD
            app.metrics.Lengths = [app.Glinks.pixLength]*pix_to_mm; %length of each branch and segment in mm
            app.metrics.totalLength = round(sum(app.metrics.Lengths),2); %In mm
            app.metrics.VLD_pct = round(app.metrics.totalLength*pix_to_mm/imgArea_mm,4)*100; %in percent
            app.metrics.VLD_mminv = round(app.metrics.totalLength/imgArea_mm,4); %in inverse mm 
            app.metrics.MeanLength = round(mean(app.metrics.Lengths*1000)); %in microns
            app.metrics.MedianLength = round(median(app.metrics.Lengths*1000)); %in microns
            
            %Average diameter
            app.metrics.Diams = [app.Glinks.diameter]*pix_to_mm*1000; %diameter of each branch and segment in microns
            app.metrics.MeanDiam = round(mean(app.metrics.Diams),2); %In microns
            app.metrics.MedianDiam = round(median(app.metrics.Diams),2);
            
            %Branchpoint density
            app.metrics.nodes = sum(~[app.Gnodes.ep]);
            app.metrics.BD =  round(app.metrics.nodes/app.metrics.totalLength,4); %in 1/mm
            
            %Tortuosity
            app.metrics.Tortuosity = [app.Glinks.tortuosity];
            app.metrics.MeanTortuosity = round(mean(app.metrics.Tortuosity(~isinf(app.metrics.Tortuosity))),4);
            
            %Fractal dimension - box counting method
            slope = fractalDimension(app);
            app.metrics.FD = round(slope,4);


            %Additional metrics to describe distribution of metircs,
            %legnths, and tortuosity: variance, kurtosis, skewness
            
            app.metrics.varDiams = round(var(app.metrics.Diams));
            app.metrics.skewDiams = round(skewness(app.metrics.Diams),2);
            app.metrics.kurtDiams = round(kurtosis(app.metrics.Diams),2);
            
            app.metrics.varLengths = round(var(app.metrics.Lengths*1000));
            app.metrics.skewLengths = round(skewness(app.metrics.Lengths*1000),2);
            app.metrics.kurtLengths = round(kurtosis(app.metrics.Lengths*1000),2);
            
            app.metrics.varTort = round(var(app.metrics.Tortuosity(~isinf(app.metrics.Tortuosity))),3);
            app.metrics.skewTort = round(skewness(app.metrics.Tortuosity(~isinf(app.metrics.Tortuosity))),2);
            app.metrics.kurtTort = round(kurtosis(app.metrics.Tortuosity(~isinf(app.metrics.Tortuosity))));
            
            
            %Add to cumulative thing for saving session
            app.metrics_counter = app.metrics_counter+1;
            app.metrics_cumulative(app.metrics_counter).name = app.metrics.name;
            app.metrics_cumulative(app.metrics_counter).nodes = app.metrics.nodes;
            app.metrics_cumulative(app.metrics_counter).VAD = app.metrics.VAD;
            app.metrics_cumulative(app.metrics_counter).totalLength = app.metrics.totalLength;
            app.metrics_cumulative(app.metrics_counter).VLD_pct = app.metrics.VLD_pct;
            app.metrics_cumulative(app.metrics_counter).VLD_mminv = app.metrics.VLD_mminv;
            app.metrics_cumulative(app.metrics_counter).MeanLength = app.metrics.MeanLength;
            app.metrics_cumulative(app.metrics_counter).MedianLength = app.metrics.MedianLength;
            app.metrics_cumulative(app.metrics_counter).MeanDiam = app.metrics.MeanDiam;
            app.metrics_cumulative(app.metrics_counter).MedianDiam = app.metrics.MedianDiam;
            app.metrics_cumulative(app.metrics_counter).BD = app.metrics.BD;
            app.metrics_cumulative(app.metrics_counter).FD = app.metrics.FD;
            app.metrics_cumulative(app.metrics_counter).mTort = app.metrics.MeanTortuosity;
            app.metrics_cumulative(app.metrics_counter).varDiams = app.metrics.varDiams;
            app.metrics_cumulative(app.metrics_counter).skewDiams = app.metrics.skewDiams;
            app.metrics_cumulative(app.metrics_counter).kurtDiams = app.metrics.kurtDiams;
            app.metrics_cumulative(app.metrics_counter).varLengths = app.metrics.varLengths;
            app.metrics_cumulative(app.metrics_counter).skewLengths = app.metrics.skewLengths;
            app.metrics_cumulative(app.metrics_counter).kurtLengths = app.metrics.kurtLengths;
            app.metrics_cumulative(app.metrics_counter).varTort = app.metrics.varTort;
            app.metrics_cumulative(app.metrics_counter).skewTort = app.metrics.skewTort;
            app.metrics_cumulative(app.metrics_counter).kurtTort = app.metrics.kurtTort;
            
            if app.FrangiBool.Value
                app.metrics_cumulative(app.metrics_counter).Frangi_sigma_max = app.sigmamaxSpinner.Value;
                app.metrics_cumulative(app.metrics_counter).Frangi_sigma_min = app.sigmaminSpinner.Value;
            else
                app.metrics_cumulative(app.metrics_counter).Frangi_sigma_max = 0;
                app.metrics_cumulative(app.metrics_counter).Frangi_sigma_min = 0;
            end
            if app.MedfiltCheckBox.Value
                app.metrics_cumulative(app.metrics_counter).MedFilt_Kernel = app.kernelSize.Value;
            else
                app.metrics_cumulative(app.metrics_counter).MedFilt_Kernel = 0;
            end
            app.metrics_cumulative(app.metrics_counter).Binarization = app.SegmentationmethodDropDown.Value;
            app.metrics_cumulative(app.metrics_counter).TwigSize = app.TwigsizeSpinner.Value;
            
            %Save to excell file for that dataset
            
            datatable = struct2table(app.metrics_cumulative(app.metrics_counter));
            ExcelFilename = fullfile(app.SaveFolder, [app.FileLabelName '_' app.datanum' '.xlsx']);
            writetable(datatable,ExcelFilename, 'Sheet', 'General metrics');
            
            writematrix(app.metrics.Diams,ExcelFilename, 'Sheet', 'Diameters');
            writematrix(app.metrics.Lengths,ExcelFilename, 'Sheet', 'Lengths');
            writematrix(app.metrics.Tortuosity,ExcelFilename, 'Sheet', 'Tortuosities');


        end
        
        function FAZAnalysis(app)
            I = app.BinaryImage;
            
            %pull faz outline to send to measure faz
            %dilate to initialize
            %erode for stop condition
            fmask = app.FAZmask;
            [fr,fc] = size(fmask);

            if app.TuneFAZsegmentationCheckBox.Value 
            
                if strcmp(app.FAZtype,'Rapid') || strcmp(app.FAZtype,'Draw')
                    dfactor = ceil(mean(fr,fc)*0.15);
                    chv_init = imdilate(fmask,offsetstrel('ball',dfactor,dfactor));
                    chv_init = chv_init > mode(chv_init);
                    %chv_stop = imerode(fmask,offsetstrel('ball',dfactor,dfactor));
                    %chv_stop = chv_stop > mode(chv_stop);
                    chv_stop=fmask;
                    chv_stop_area = sum(sum(chv_stop));
                else 
                    dfactor = ceil(mean(fr,fc)*0.5);
                    chv_init = imdilate(fmask,offsetstrel('ball',dfactor,dfactor));
                    chv_init = chv_init > mode(chv_init);
                    %chv_stop = imerode(fmask,offsetstrel('ball',dfactor,dfactor));
                    %chv_stop = chv_stop > mode(chv_stop);
                    chv_stop=fmask;
                    chv_stop_area = sum(sum(chv_stop));
                
                end 
            else
                chv_init = fmask;
                chv_init = chv_init > mode(chv_init);
                chv_stop=fmask;
                chv_stop_area = sum(sum(chv_stop));
            end

                       
            %Calcualate initial contour value based on mask 
            chv_init_pts = bwboundaries(chv_init);
            chv_init_pts = chv_init_pts{1};
            chv_stop_pts = bwboundaries(chv_stop);
            chv_stop_pts = chv_stop_pts{1};
            size(chv_init_pts);
            x1 = chv_init_pts(:,2);
            y1 = chv_init_pts(:,1);
            %x2 = chv_stop_pts(:,2);
            %y2 = chv_stop_pts(:,1);
            
            %figure(3);
            %title('Active Contour Initialization and Hard Stop')
            %imshow(app.BinaryImage); hold on;
            %plot(x1,y1,'g','LineWidth',2);
            %plot(x2,y2,'r','LineWidth',2);
            
            % the levelset function
            [x,y] = meshgrid(1:size(I,2),1:size(I,1));
            m = inpolygon(x,y,x1,y1);
            phi = bwdist(1-m)-bwdist(m)-m;


            %If tune FAZ check box is selected, then run chanvese algorithm
            %to optimize countour. Othewise, just use inital contour. 
           
            if app.TuneFAZsegmentationCheckBox.Value  
                %Setup figure window with UI controls
                
                fid = uifigure('Name','find FAZ','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                imaxs = uiaxes("Parent",fid,'Position',[120,20,400,400]);
                
                my = 20;
                lambda1 = 5;
                lambda2 = 1;
                stopping = false;
                
                imshow(I,"Parent",imaxs);
                stopbutton = uibutton(fid,"push",'Text','Stop','Position',[30 35 60 20]);
                stopbutton.ButtonPushedFcn = {@(src,evnt)stopButtonPushed};

                 % get the initial contour
                hold(imaxs, "on")
                %title('Initial contour');
                contour(phi, [0 0], 'r', 'LineWidth',2,"Parent",imaxs);
                drawnow;
                hold(imaxs, "off")


                %Apply iterations to optimize contour
                dt = 1;
                a_check = fr*fc;
                
                for i=1:1000 %Change to while loop so it looks for convergence?
                    %This works, but it takes a long time - alternative to
                    %add a "stop" button when convergence is reached
                    %get the c- and c+
                    heavi = heaviside(phi);
                    cp = sum(sum(heavi.*I))/length(find(phi>=0)); %inside mean gray level
                    cm = sum(sum((1-heavi).*I))/length(find(phi<0)); %outside mean gray level
                    % Curvature
                    kappa = curvature(phi);
                    
                    % Iterative step
                    phi_t = diracdelta(phi).*(my*kappa - lambda1*(I-cp).^2 + lambda2*(I-cm).^2);
                    phi = phi + dt.*(phi_t./(max(max(abs(phi_t)))));
                    
                    phi = reinitialization(phi, 0.5);
                    
                    if length(find(phi>=0)) < 0.75*chv_stop_area
                        FAZ_Mask=chv_stop;
                        break;
                    end
                    
                    % Print contour
                    if(mod(i-1,10) == 0)
                        imshow(app.BinaryImage,"Parent",imaxs);
                        hold(imaxs, "on")
                        [c,h]=contour(phi, [0 0], 'r', 'LineWidth',2,"Parent",imaxs);
                        imaxs.Title.String = [ num2str(i) ' iterations'];
                        drawnow;
                        hold(imaxs,"off")
                        % check if area n*10th iteraion is less than
                        % (n-1)*10th; if not --> stop active contour
                        
                        if length(find(phi>=0)) < a_check
                            FAZ_Mask=zeros(1,1);
                            a_check=length(find(phi>=0));
                        else
                            FAZ_Mask=chv_stop;
                            break;
                        end
                        
                        %xlabel('The iteration can be stopped by pressing the OK button')
                    end
                    % Reinitialize the level set equation phi
                    
                    if stopping
                        break
                    end                    
                end
                
                imshow(I,"Parent",imaxs);
                hold(imaxs,'on');
                imaxs.Title.String = ['Final contour on original image after ' num2str(i) ' iterations'];
                [c,h]=contour(phi, [0 0], 'y', 'LineWidth',2,"Parent",imaxs,"Visible","off"); drawnow; %hold off;
                %To remove the small circles
                [s,index]=get_lines(c);
                %s(index).x,s(index).y are the co
                plot(s(index).x,s(index).y,'r','LineWidth',2,"Parent",imaxs);
                %get the center of the avascular zone
                [Cx,Cy]=get_central(s(index).x,s(index).y);
                plot(Cx,Cy,'r*',"Parent",imaxs);
                drawnow

                FAZ_Mask = poly2mask(s(index).x,s(index).y,size(I,1),size(I,2)); 

            else
                FAZ_Mask = fmask; 
                
                fid = uifigure('Name','find FAZ','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                imaxs = uiaxes("Parent",fid,'Position',[120,20,400,400]);
                %Use values from initial contour 
                imshow(I,"Parent",imaxs);
                hold(imaxs,'on');
                imaxs.Title.String = 'Final contour on original image: auto contour';
                [c,h]=contour(phi, [0 0], 'y', 'LineWidth',2,"Parent",imaxs,"Visible","off"); drawnow; %hold off;
                %To remove the small circles
                [s,index]=get_lines(c);
                %s(index).x,s(index).y are the co
                plot(s(index).x,s(index).y,'r','LineWidth',2,"Parent",imaxs);
                %get the center of the avascular zone
                [Cx,Cy]=get_central(s(index).x,s(index).y);
                plot(Cx,Cy,'r*',"Parent",imaxs);
                drawnow
            end

                % Calculate foveal FAZ
                %calculate position vectors for points relative to centroid
                app.FAZ_ctr = [Cx,Cy];
                cnt = s(index);
                mask1 = poly2mask(cnt.x,cnt.y,app.fullImSizePix(1),app.fullImSizePix(2));
                
                cnt.x = cnt.x-Cx;
                cnt.y = cnt.y-Cy;
                %convert to polar coordinates
                [cnt.theta, cnt.rho] = cart2pol(cnt.x,cnt.y);
                %Increase length of vectors by 300 microns
                radSize = .3*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                cnt.rho = cnt.rho+radSize;
                %Convert back to cartesian
                [cnt.x, cnt.y] = pol2cart(cnt.theta, cnt.rho);
                cnt.x = cnt.x+Cx;
                cnt.y = cnt.y+Cy;
                %Generate mask based on 2 contours - sets everything to NaN and only keeps the data between the two rings
                mask1 = poly2mask(cnt.x,cnt.y,app.fullImSizePix(1),app.fullImSizePix(2))-mask1;
                mask1(mask1==0) = nan;
                nPix = nansum(nansum(mask1));
                %Apply mask to binary image
                maskBin = mask1.*app.BinaryImage;
                FD_300 = nansum(nansum(maskBin))/255/nPix*100;
                
                %Save figure so contour can be checked later
                fol = fullfile(app.SaveFolder,'images');
                if ~exist(fol, 'dir')
                    mkdir(fol)
                    imgnum = 0;
                else
                    list = dir(fullfile(fol, '*FAZ_Countour*.png'));
                    imgnum = length(list);
                end
                
                savename_FAZ1 = fullfile(fol,[app.FileLabelName '_FAZ_Countour_' num2str(imgnum) '.png']);
                savename_FAZ2 = fullfile(fol,[app.FileLabelName '_FAZ300_Countour_' num2str(imgnum) '.png']);
                savename_FAZ3 = fullfile(fol,[app.FileLabelName '_FAZ_Countour_both_' num2str(imgnum) '.png']);
                savename_FAZ4 = fullfile(fol,[app.FileLabelName '_FAZ_mask_' num2str(imgnum) '.png']);

                fid2 = figure;
                set(fid2,'Visible','off')
                imshow(I)
                % if app.TuneFAZsegmentationCheckBox.Value 
                %     title(['Final contour on original image after ' num2str(i) ' iterations']);
                % else
                %     title('Final contour on original image: auto contour');
                % end
                hold on
                contour(phi, [0 0], 'y', 'LineWidth',2,"Visible","off");
                plot(s(index).x,s(index).y,'r','LineWidth',2);
                drawnow;
                %saveas(fid2, savename_FAZ1,'png');%Save eith just primary FAZ contour 
                export_fig(fid2,savename_FAZ1)
                close(fid2)

                fid2 = figure;
                set(fid2,'Visible','off')
                imshow(I)
                hold on
                plot(cnt.x, cnt.y,'y','LineWidth',2)
                %title('FD300 contour')
                drawnow
                %saveas(fid2, savename_FAZ2,'png');%Save eith just FD300 contour 
                export_fig(fid2,savename_FAZ2)

                plot(s(index).x,s(index).y,'r','LineWidth',2);
                plot(Cx,Cy,'r*')
                %title('')
                drawnow;
                %saveas(fid2, savename_FAZ3,'png');%Plot with both contours and centermark 
                export_fig(fid2,savename_FAZ3)
                close(fid2)

                fid2 = figure;
                set(fid2,'Visible','off')
                imshow(FAZ_Mask)
                drawnow
                %saveas(fid2, savename_FAZ4,'png'); %Mask only 
                export_fig(fid2,savename_FAZ4)
                close(fid2)

                
%                 %Second method using binarization
%                 [ca,cb]=size(FAZ_Mask);
%                 if ca==1
%                     FAZ_Mask = poly2mask(s(index).x,s(index).y,size(I,1),size(I,2));      
%                 else  
%                     FAZ_Mask=app.FAZmask;
%                     FD_300=0; %FIX THIS???  
%                 end
            
            FAZ_stats = regionprops(FAZ_Mask,'Area','Centroid','Circularity','Eccentricity', 'MajoraxisLength','MinoraxisLength','Perimeter');
            FAZ_stats.acircularity_index = FAZ_stats.Perimeter/(2*pi*sqrt(FAZ_stats.Area/pi));
            FAZ_stats.axis_ratio = FAZ_stats.MajorAxisLength/FAZ_stats.MinorAxisLength;
            FAZ_stats.FD_300 = round(FD_300,1);
            FAZ_stats.name = app.FileLabelName;
            
            %Convert from pixels to mm
            pix_to_mm = app.RealImageSizemm.Value/app.fullImSizePix(1);
            FAZ_stats.Area = round(FAZ_stats.Area*pix_to_mm^2,3); %In mm^2
            FAZ_stats.Centroid = round(FAZ_stats.Centroid*pix_to_mm,3); %in mm
            FAZ_stats.MajorAxisLength = round(FAZ_stats.MajorAxisLength*pix_to_mm,3); %in mm
            FAZ_stats.MinorAxisLength = round(FAZ_stats.MinorAxisLength*pix_to_mm,3); %in mm
            FAZ_stats.Perimeter = round(FAZ_stats.Perimeter * pix_to_mm,3); %in mm
            
            %Eccentricity, circularity, acircularity index, axis ratio
            %are unitless but should still be rounded
            FAZ_stats.Eccentricity = round(FAZ_stats.Eccentricity,2);
            FAZ_stats.Circularity = round(FAZ_stats.Circularity,2);
            FAZ_stats.acircularity_index = round(FAZ_stats.acircularity_index,2);
            FAZ_stats.axis_ratio = round(FAZ_stats.axis_ratio,2);
            
            
            %Save Excel file with results summary
            datatable = struct2table(FAZ_stats);
            ExcelFilename = fullfile(app.SaveFolder, [app.FileLabelName '_' app.datanum' '.xlsx']);
            writetable(datatable,ExcelFilename, 'Sheet', 'FAZ metrics');
            close(fid)
            
            
            %Add data to saved session
            app.FAZ_metrics_counter = app.FAZ_metrics_counter+1;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).name = app.FileLabelName;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).Area = FAZ_stats.Area;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).Centroid = FAZ_stats.Centroid;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).Circularity = FAZ_stats.Circularity;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).Eccentricity = FAZ_stats.Eccentricity;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).MajoraxisLength = FAZ_stats.MajorAxisLength;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).MinoraxisLength = FAZ_stats.MinorAxisLength;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).Perimeter = FAZ_stats.Perimeter;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).acircularity_index = FAZ_stats.acircularity_index;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).axis_ratio = FAZ_stats.axis_ratio;
            app.FAZ_metrics_cumulative(app.FAZ_metrics_counter).FD_300 = FAZ_stats.FD_300;
            
            
            function Y = heaviside(X)
                epsilon = 0.3;
                Y=1/2*(1+2/pi*atan(X/epsilon));
            end
            
            function d = diracdelta(X)
                epsilon = 0.3;
                d = (pi*(epsilon^2+X.^2)).\epsilon;
            end
            
            function stopButtonPushed()
                stopping = true;
            end
        end
        
        function regionalmetrics = regionalAnalysis(app)
            pix_to_mm = app.RealImageSizemm.Value/app.fullImSizePix(1);
            %Make a folder for storing the regional analysis results
            
            fol = fullfile(app.SaveFolder);
            
            
            %Initialize a new structure array for the regional analysis
            %metrics

            %imwrite(app.inputImage,"5000original.png");
            
            grid_type = app.GridType.Value;
            
            if strcmp(grid_type,'Square grid')
                regionsize = floor(app.fullImSizePix./3);
                
                binaryReg{1} = app.BinaryImage(1:regionsize(1),1:regionsize(2));
                binaryReg{2}  = app.BinaryImage(1:regionsize(1),regionsize(2)+1:2*regionsize(2));
                binaryReg{3} = app.BinaryImage(1:regionsize(1),2*regionsize(2)+1:3*regionsize(2));
                binaryReg{4} = app.BinaryImage(regionsize(1)+1:2*regionsize(1),1:regionsize(2));
                binaryReg{5}  = app.BinaryImage(regionsize(1)+1:2*regionsize(1),regionsize(2)+1:2*regionsize(2));
                binaryReg{6} = app.BinaryImage(regionsize(1)+1:2*regionsize(1),2*regionsize(2)+1:3*regionsize(2));
                binaryReg{7}  = app.BinaryImage(2*regionsize(1)+1:3*regionsize(1),1:regionsize(2));
                binaryReg{8} = app.BinaryImage(2*regionsize(1)+1:3*regionsize(1),regionsize(2)+1:2*regionsize(2));
                binaryReg{9}  = app.BinaryImage(2*regionsize(1)+1:3*regionsize(1),2*regionsize(2)+1:3*regionsize(2));

                imgReg{1} = app.inputImage(1:regionsize(1),1:regionsize(2));
                imgReg{2}  = app.inputImage(1:regionsize(1),regionsize(2)+1:2*regionsize(2));
                imgReg{3} = app.inputImage(1:regionsize(1),2*regionsize(2)+1:3*regionsize(2));
                imgReg{4} = app.inputImage(regionsize(1)+1:2*regionsize(1),1:regionsize(2));
                imgReg{5}  = app.inputImage(regionsize(1)+1:2*regionsize(1),regionsize(2)+1:2*regionsize(2));
                imgReg{6} = app.inputImage(regionsize(1)+1:2*regionsize(1),2*regionsize(2)+1:3*regionsize(2));
                imgReg{7}  = app.inputImage(2*regionsize(1)+1:3*regionsize(1),1:regionsize(2));
                imgReg{8} = app.inputImage(2*regionsize(1)+1:3*regionsize(1),regionsize(2)+1:2*regionsize(2));
                imgReg{9}  = app.inputImage(2*regionsize(1)+1:3*regionsize(1),2*regionsize(2)+1:3*regionsize(2));
                
                %Prepare for metric generation
                imgArea_pix = regionsize(1)*regionsize(2);
                imgArea_mm = imgArea_pix*pix_to_mm^2;
                
                
                if strcmp(app.RL.Value,'Right Eye')
                    app.RAlabels = ["Supero-temporal" "Superior" "Supero-nasal" "Temporal" "Central" "Nasal" "Infero-temporal" "Inferior" "Infero-nasal"];
                else
                    app.RAlabels = ["Supero-nasal" "Superior" "Supero-temporal" "Nasal" "Central" "Temporal" "Infero-nasal" "Inferior" "Infero-temporal"];
                end
                
            elseif strcmp(grid_type,'ETDRS grid')
                % %----------------------------------------------------------
                % % Calculate each ring radius in pixels
                % %----------------------------------------------------------
                % rad_1mm = 0.5 * app.fullImSizePix(1) / app.RealImageSizemm.Value;
                % rad_3mm = 1.5 * app.fullImSizePix(1) / app.RealImageSizemm.Value;
                % 
                % %----------------------------------------------------------
                % % Figure for overlay (off-screen but exportable)
                % %----------------------------------------------------------
                % fig = figure('Visible','off','Color','w');
                % imshow(app.inputImage, [], 'InitialMagnification','fit');
                % colormap gray
                % axis equal
                % hold on
                % xlim([0 app.fullImSizePix(1)])
                % ylim([0 app.fullImSizePix(2)])
                % set(gca,'YDir','reverse') % match image coordinate direction
                % 
                % %----------------------------------------------------------
                % % FAZ center
                % %----------------------------------------------------------
                % if isempty(app.FAZ_ctr)
                %     app.FAZ_ctr = app.fullImSizePix/2;
                % end
                % 
                % %----------------------------------------------------------
                % % Circles + masks
                % %----------------------------------------------------------
                % roi_1mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_1mm,"LineWidth",2,"Color",'y');
                % roi_3mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_3mm,"LineWidth",2,"Color",'y');
                % 
                % foveal_mask = roi_1mm.createMask; % Foveal circle
                % parafoveal_mask = roi_3mm.createMask - roi_1mm.createMask; % Parafoveal rim
                % 
                % %----------------------------------------------------------
                % % Sector masks (6 wedges)
                % %----------------------------------------------------------
                % sector_masks = zeros(app.fullImSizePix(1), app.fullImSizePix(2), 6);
                % colors = lines(6);
                % 
                % for ii = 1:6
                %     theta = linspace(0, -60, 1000) - 60*(ii-1);
                % 
                %     x_sector = [app.FAZ_ctr(1) rad_3mm*cosd(theta)+app.FAZ_ctr(1) app.FAZ_ctr(1)];
                %     y_sector = [app.FAZ_ctr(2) rad_3mm*sind(theta)+app.FAZ_ctr(2) app.FAZ_ctr(2)];
                % 
                %     % draw wedge boundary
                %     plot(x_sector, y_sector, 'LineWidth', 2, 'Color', colors(ii,:));
                % 
                %     % wedge mask (excluding fovea)
                %     mask = poly2mask(x_sector, y_sector, app.fullImSizePix(1), app.fullImSizePix(2));
                %     sector_masks(:,:,ii) = (mask - foveal_mask) > 0;
                % end
                % 
                % %----------------------------------------------------------
                % % Optional wedge labels S1..S6 (helps compare to Python)
                % %----------------------------------------------------------
                % for ii = 1:6
                %     theta_mid = -30 - 60*(ii-1);
                %     x_lab = app.FAZ_ctr(1) + (rad_3mm + 20) * cosd(theta_mid);
                %     y_lab = app.FAZ_ctr(2) + (rad_3mm + 20) * sind(theta_mid);
                %     text(x_lab, y_lab, sprintf('S%d', ii), ...
                %         'Color', colors(ii,:), 'FontSize', 14, 'FontWeight', 'bold');
                % end
                % plot(app.FAZ_ctr(1), app.FAZ_ctr(2), 'wo', 'MarkerFaceColor','w', 'MarkerSize', 8);
                % 
                % %----------------------------------------------------------
                % % Save overlay PNG BEFORE closing figure
                % %----------------------------------------------------------
                % overlayFile = fullfile(app.SaveFolder, [app.FileLabelName '_ETDRS_overlay.png']);
                % axis off
                % exportgraphics(fig, overlayFile, 'Resolution', 300);
                % close(fig)
                % 
                % %----------------------------------------------------------
                % % Region extraction (UNCHANGED from your original code)
                % %----------------------------------------------------------
                % binaryReg{1} = app.BinaryImage.*foveal_mask; % foveal circle
                % binaryReg{1} = binaryReg{1}( ...
                %     round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), ...
                %     round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))) );
                % 
                % binaryReg{2} = app.BinaryImage.*parafoveal_mask; % parafoveal rim
                % 
                % binaryReg{3} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)); % superior hemi
                % binaryReg{3} = binaryReg{3}(1:round(app.fullImSizePix(1)/2),:);
                % 
                % binaryReg{4} = app.BinaryImage.*(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6)); % inferior hemi
                % binaryReg{4} = binaryReg{4}(round(app.fullImSizePix(1)/2+1):end,:);
                % 
                % binaryReg{5} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,6)); % nasal/temporal half
                % binaryReg{5} = binaryReg{5}(:,round(app.fullImSizePix(1)/2+1):end);
                % 
                % binaryReg{6} = app.BinaryImage.*(sector_masks(:,:,3)+sector_masks(:,:,4)); % temporal/nasal half
                % binaryReg{6} = binaryReg{6}(:,1:floor(app.fullImSizePix(1)/2));
                % 
                % binaryReg{7} = app.BinaryImage.*sector_masks(:,:,2); % superior wedge
                % binaryReg{7} = binaryReg{7}(1:round(app.fullImSizePix(1)/2),:);
                % 
                % binaryReg{8} = app.BinaryImage.*sector_masks(:,:,5); % inferior wedge
                % binaryReg{8} = binaryReg{8}(round(app.fullImSizePix(1)/2+1):end,:);
                % 
                % %----------------------------------------------------------
                % % imgReg extraction (THIS was missing; required by your loop)
                % %----------------------------------------------------------
                % imgReg{1} = app.inputImage.*foveal_mask; % foveal circle
                % imgReg{1} = imgReg{1}( ...
                %     round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), ...
                %     round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))) );
                % 
                % imgReg{2} = app.inputImage.*parafoveal_mask; % parafoveal rim
                % 
                % imgReg{3} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)); % superior hemi
                % imgReg{3} = imgReg{3}(1:round(app.fullImSizePix(1)/2),:);
                % 
                % imgReg{4} = app.inputImage.*(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6)); % inferior hemi
                % imgReg{4} = imgReg{4}(round(app.fullImSizePix(1)/2+1):end,:);
                % 
                % imgReg{5} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,6)); % nasal/temporal half
                % imgReg{5} = imgReg{5}(:,round(app.fullImSizePix(1)/2+1):end);
                % 
                % imgReg{6} = app.inputImage.*(sector_masks(:,:,3)+sector_masks(:,:,4)); % temporal/nasal half
                % imgReg{6} = imgReg{6}(:,1:floor(app.fullImSizePix(1)/2));
                % 
                % imgReg{7} = app.inputImage.*sector_masks(:,:,2); % superior wedge
                % imgReg{7} = imgReg{7}(1:round(app.fullImSizePix(1)/2),:);
                % 
                % imgReg{8} = app.inputImage.*sector_masks(:,:,5); % inferior wedge
                % imgReg{8} = imgReg{8}(round(app.fullImSizePix(1)/2+1):end,:);
                % 
                % %----------------------------------------------------------
                % % Labels (unchanged)
                % %----------------------------------------------------------
                % if strcmp(app.RL.Value,'Right Eye')
                %     app.RAlabels = ["Foveal circle" "Parafoveal rim " ...
                %         "Superior hemi-parafoveal rim" "Inferior hemi-parafoveal rim" ...
                %         "Nasal parafoveal rim" "Tempo parafoveal rim" ...
                %         "Superior parafoveal rim" "Inferior parafoveal rim"];
                % else
                %     app.RAlabels = ["Foveal circle" "Parafoveal rim " ...
                %         "Superior hemi-parafoveal rim" "Inferior hemi-parafoveal rim" ...
                %         "Tempo parafoveal rim" "Nasal parafoveal rim" ...
                %         "Superior parafoveal rim" "Inferior parafoveal rim"];
                % end
                % 
                % %----------------------------------------------------------
                % % Area vector (kept identical to original, including repeated last entry)
                % %----------------------------------------------------------
                % imgArea_pix_vec = [ ...
                %     sum(foveal_mask,'all') ...
                %     sum(parafoveal_mask,'all') ...
                %     sum(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3),'all') ...
                %     sum(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6),'all') ...
                %     sum(sector_masks(:,:,1)+sector_masks(:,:,6),'all') ...
                %     sum(sector_masks(:,:,3)+sector_masks(:,:,4),'all') ...
                %     sum(sector_masks(:,:,2),'all') ...
                %     sum(sector_masks(:,:,2),'all') ];
                %Calculate each ring radius in pixels
                rad_1mm = .5*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                rad_3mm = 1.5*app.fullImSizePix(1)/app.RealImageSizemm.Value;

                fig = figure;
                image(app.inputImage)
                fig.Visible = 'on';
                colormap gray
                axis equal
                xlim([0 app.fullImSizePix(1)])
                ylim([0 app.fullImSizePix(2)])
                hold on
                if isempty(app.FAZ_ctr)
                    app.FAZ_ctr = app.fullImSizePix/2;
                end

                roi_1mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_1mm,"LineWidth",2,"Color",'y');
                roi_3mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_3mm,"LineWidth",2,"Color",'y');

                foveal_mask = roi_1mm.createMask;
                parafoveal_mask = roi_3mm.createMask-roi_1mm.createMask;

                %Create a mask for each segment - start with assumption
                %of OS (right eye?) and swap labels after if needed...

                sector_masks = zeros(app.fullImSizePix(1), app.fullImSizePix(2), 6);

                for ii = 1:6
                    theta = linspace(0, -60, 1000)-60*(ii-1);
                    x_sector = [app.FAZ_ctr(1) rad_3mm*cosd(theta)+app.FAZ_ctr(1) app.FAZ_ctr(1)];
                    y_sector = [app.FAZ_ctr(2) rad_3mm*sind(theta)+app.FAZ_ctr(2) app.FAZ_ctr(2)];
                    plot(x_sector,y_sector,'LineWidth',2,"Color",'r')
                    p=area(x_sector,y_sector);
                    mask = poly2mask(x_sector, y_sector,app.fullImSizePix(1),app.fullImSizePix(2));
                    sector_masks(:,:,ii) = (mask-foveal_mask) > 0;
                end
                drawnow
                close(fig)

                binaryReg{1} = app.BinaryImage.*foveal_mask; %foveal circle
                binaryReg{1} = binaryReg{1}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                binaryReg{2} = app.BinaryImage.*parafoveal_mask; %Parafoveal rim
                binaryReg{3} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)); %superior hemi-parafoveal rim
                binaryReg{3} = binaryReg{3}(1:round(app.fullImSizePix(1)/2),:);
                binaryReg{4} = app.BinaryImage.*(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6)); %inferior hemi-parafoveal rim
                binaryReg{4} = binaryReg{4}(round(app.fullImSizePix(1)/2+1):end,:);
                binaryReg{5} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,6)); %tempo/nasal parafoveal rim
                binaryReg{5} = binaryReg{5}(:,round(app.fullImSizePix(1)/2+1):end);
                binaryReg{6} = app.BinaryImage.*(sector_masks(:,:,3)+sector_masks(:,:,4));%tempo/nasal parafoveal rim
                binaryReg{6} = binaryReg{6}(:,1:floor(app.fullImSizePix(1)/2));
                binaryReg{7} = app.BinaryImage.*sector_masks(:,:,2); %superior-parafoveal rim
                binaryReg{7} = binaryReg{7}(1:round(app.fullImSizePix(1)/2),:);
                binaryReg{8} = app.BinaryImage.*sector_masks(:,:,5); %inferior-parafoveal rim
                binaryReg{8} = binaryReg{8}(round(app.fullImSizePix(1)/2+1):end,:);

                imgReg{1} = app.inputImage.*foveal_mask; %foveal circle
                imgReg{1} = imgReg{1}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                imgReg{2} = app.inputImage.*parafoveal_mask; %Parafoveal rim
                imgReg{3} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)); %superior hemi-parafoveal rim
                imgReg{3} = imgReg{3}(1:round(app.fullImSizePix(1)/2),:);
                imgReg{4} = app.inputImage.*(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6)); %inferior hemi-parafoveal rim
                imgReg{4} = imgReg{4}(round(app.fullImSizePix(1)/2+1):end,:);
                imgReg{5} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,6)); %tempo/nasal parafoveal rim
                imgReg{5} = imgReg{5}(:,round(app.fullImSizePix(1)/2+1):end);
                imgReg{6} = app.inputImage.*(sector_masks(:,:,3)+sector_masks(:,:,4));%tempo/nasal parafoveal rim
                imgReg{6} = imgReg{6}(:,1:floor(app.fullImSizePix(1)/2));
                imgReg{7} = app.inputImage.*sector_masks(:,:,2); %superior-parafoveal rim
                imgReg{7} = imgReg{7}(1:round(app.fullImSizePix(1)/2),:);
                imgReg{8} = app.inputImage.*sector_masks(:,:,5); %inferior-parafoveal rim
                imgReg{8} = imgReg{8}(round(app.fullImSizePix(1)/2+1):end,:);

                if strcmp(app.RL.Value,'Right Eye')
                    app.RAlabels = ["Foveal circle" "Parafoveal rim " "Superior hemi-parafoveal rim" "Inferior hemi-parafoveal rim" "Nasal parafoveal rim" "Tempo parafoveal rim" "Superior parafoveal rim" "Inferior parafoveal rim"];
                else
                    app.RAlabels = ["Foveal circle" "Parafoveal rim " "Superior hemi-parafoveal rim" "Inferior hemi-parafoveal rim" "Tempo parafoveal rim" "Nasal parafoveal rim" "Superior parafoveal rim" "Inferior parafoveal rim"];
                end

                imgArea_pix_vec = [sum(sum(foveal_mask)) sum(sum(parafoveal_mask)) sum(sum(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)))...
                    sum(sum(sector_masks(:,:,4)+sector_masks(:,:,5)+sector_masks(:,:,6))) sum(sum(sector_masks(:,:,1)+sector_masks(:,:,6))) sum(sum(sector_masks(:,:,3)+sector_masks(:,:,4)))...
                    sum(sum(sector_masks(:,:,2))) sum(sum(sector_masks(:,:,2)))];
            elseif strcmp(grid_type,'Extended ETDRS grid')  
                %Calculate each ring radius in pixels
                rad_1mm = .5*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                rad_3mm = 1.5*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                
                fig = figure;
                image(app.inputImage)
                fig.Visible = 'off';
                colormap gray
                axis equal
                xlim([0 app.fullImSizePix(1)])
                ylim([0 app.fullImSizePix(2)])
                hold on
                if isempty(app.FAZ_ctr)
                    app.FAZ_ctr = app.fullImSizePix/2;
                end
                
                roi_1mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_1mm,"LineWidth",2,"Color",'y');
                roi_3mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_3mm,"LineWidth",2,"Color",'y');

                foveal_mask = roi_1mm.createMask;
                parafoveal_mask = roi_3mm.createMask-roi_1mm.createMask;
                
                %Create a mask for each segment - start with assumption
                %of OS (right eye?) and swap labels after if needed...
                
                sector_masks = zeros(app.fullImSizePix(1), app.fullImSizePix(2), 6);
                
                for ii = 1:8
                    theta = linspace(0, -45, 1000)-45*(ii-1);
                    x_sector = [app.FAZ_ctr(1) rad_3mm*cosd(theta)+app.FAZ_ctr(1) app.FAZ_ctr(1)];
                    y_sector = [app.FAZ_ctr(2) rad_3mm*sind(theta)+app.FAZ_ctr(2) app.FAZ_ctr(2)];
                    plot(x_sector,y_sector,'LineWidth',2,"Color",'r')
                    mask = poly2mask(x_sector, y_sector,app.fullImSizePix(1),app.fullImSizePix(2));
                    sector_masks(:,:,ii) = (mask-foveal_mask) > 0;
                end
                drawnow
                close(fig)
                
                %Regions include 8 45-degree wedges + Nasal and Temporal 90
                %degree wedges 
                
                binaryReg{1} = app.BinaryImage.*sector_masks(:,:,1);
                binaryReg{1} = binaryReg{1}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                binaryReg{2} = app.BinaryImage.*sector_masks(:,:,2);
                binaryReg{2} = binaryReg{2}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                binaryReg{3} = app.BinaryImage.*sector_masks(:,:,3);
                binaryReg{3} = binaryReg{3}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                binaryReg{4} = app.BinaryImage.*sector_masks(:,:,4);
                binaryReg{4} = binaryReg{4}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                binaryReg{5} = app.BinaryImage.*sector_masks(:,:,5);
                binaryReg{5} = binaryReg{5}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                binaryReg{6} = app.BinaryImage.*sector_masks(:,:,6);
                binaryReg{6} = binaryReg{6}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                binaryReg{7} = app.BinaryImage.*sector_masks(:,:,7);
                binaryReg{7} = binaryReg{7}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                binaryReg{8} = app.BinaryImage.*sector_masks(:,:,8);
                binaryReg{8} = binaryReg{8}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                binaryReg{9} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,8));
                binaryReg{9} = binaryReg{9}(:,round(app.fullImSizePix(1)/2):end);
                binaryReg{10} = app.BinaryImage.*(sector_masks(:,:,4)+sector_masks(:,:,5));
                binaryReg{10} = binaryReg{10}(:,1:round(app.fullImSizePix(1)/2));

                imgReg{1} = app.inputImage.*sector_masks(:,:,1);
                imgReg{1} = imgReg{1}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                imgReg{2} = app.inputImage.*sector_masks(:,:,2);
                imgReg{2} = imgReg{2}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                imgReg{3} = app.inputImage.*sector_masks(:,:,3);
                imgReg{3} = imgReg{3}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                imgReg{4} = app.inputImage.*sector_masks(:,:,4);
                imgReg{4} = imgReg{4}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                imgReg{5} = app.inputImage.*sector_masks(:,:,5);
                imgReg{5} = imgReg{5}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                imgReg{6} = app.inputImage.*sector_masks(:,:,6);
                imgReg{6} = imgReg{6}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                imgReg{7} = app.inputImage.*sector_masks(:,:,7);
                imgReg{7} = imgReg{7}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                imgReg{8} = app.inputImage.*sector_masks(:,:,8);
                imgReg{8} = imgReg{8}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                imgReg{9} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,8));
                imgReg{9} = imgReg{9}(:,round(app.fullImSizePix(1)/2):end);
                imgReg{10} = app.inputImage.*(sector_masks(:,:,4)+sector_masks(:,:,5));
                imgReg{10} = imgReg{10}(:,1:round(app.fullImSizePix(1)/2));

                if strcmp(app.RL.Value,'Right Eye')
                    app.RAlabels = ["Super nasal" "Nasal superior" "Temporal superior" "Super temporal" "Inferio temporal" "Temporal inferior" "Nasal inferior" "Inferio nasal" "Nasal wedge" "Temporal wedge"];
                else
                    app.RAlabels = ["Super temporal" "Temporal superior" "Nasal superior" "Super nasal" "Inferio nasal" "Nasal inferior" "Temporal inferior" "Inferio temporal" "Temporal wedge" "Nasal wedge"];
                end

                imgArea_pix_vec = [sum(sector_masks(:,:,1),'all') sum(sector_masks(:,:,2),'all') sum(sector_masks(:,:,3),'all') sum(sector_masks(:,:,4),'all') sum(sector_masks(:,:,5),'all')...
                    sum(sector_masks(:,:,6),'all') sum(sector_masks(:,:,7),'all') sum(sector_masks(:,:,8),'all') sum(sector_masks(:,:,1)+sector_masks(:,:,8),'all') sum(sector_masks(:,:,4)+sector_masks(:,:,5),'all')];
                
                grid_type = 'Extended';

            elseif strcmp(grid_type,'Optic nerve head')  
                %Calculate each ring radius in pixels
                rad_1mm = 1*app.fullImSizePix(1)/app.RealImageSizemm.Value;%Inner excluded ring is 2 mm in diameter
                rad_outer = 2*app.fullImSizePix(1)/app.RealImageSizemm.Value; %The outer ring may need to be 2.5, 3, or 4.25 - The user will need to input this 


                %Setup to save figure so contour can be checked later
                fol = fullfile(app.SaveFolder,'images');
                if ~exist(fol, 'dir')
                    mkdir(fol)
                    imgnum = 0;
                else
                    list = dir(fullfile(fol, '*ONH_Countour*.png'));
                    imgnum = length(list);
                end
                
                savename_ONH = fullfile(fol,[app.FileLabelName '_ONH_Countour_' num2str(imgnum) '.png']);

                
                fig = figure;
                image(app.inputImage)
                fig.Visible = 'off';
                colormap gray
                axis equal
                xlim([0 app.fullImSizePix(1)])
                ylim([0 app.fullImSizePix(2)])
                hold on
                if isempty(app.FAZ_ctr)
                    app.FAZ_ctr = app.fullImSizePix/2;
                end
                
                roi_1mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_1mm,"LineWidth",2,"Color",'y',"FaceAlpha",0);
                roi_outer = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_outer, "LineWidth",2,"Color",'r',"FaceAlpha",0);

                ONH_mask_inner = roi_1mm.createMask;
                PP_mask = roi_outer.createMask-roi_1mm.createMask; %Peripapaillary

                %Save image with rings 
                axis off
                export_fig(fig,savename_ONH)

                %Need to make 8 sectors, similar to EDTRS grid 
                sector_masks = zeros(app.fullImSizePix(1), app.fullImSizePix(2), 6);
                
                for ii = 1:8
                    theta = linspace(0, -45, 1000)-45*(ii-1);
                    x_sector = [app.FAZ_ctr(1) rad_outer*cosd(theta)+app.FAZ_ctr(1) app.FAZ_ctr(1)];
                    y_sector = [app.FAZ_ctr(2) rad_outer*sind(theta)+app.FAZ_ctr(2) app.FAZ_ctr(2)];
                    plot(x_sector,y_sector,'LineWidth',2,"Color",'r')
                    mask = poly2mask(x_sector, y_sector,app.fullImSizePix(1),app.fullImSizePix(2));
                    sector_masks(:,:,ii) = (mask-ONH_mask_inner) > 0;
                end
                drawnow
                close(fig)

                %Define regions - each for 8 45 degree wedges on its own +
                %extra = 14 total + peripaillary defined above 
                %regions 

                binaryReg{1} = app.BinaryImage.*sector_masks(:,:,1); %TS
                binaryReg{1} = binaryReg{1}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                binaryReg{2} = app.BinaryImage.*sector_masks(:,:,2); %ST
                binaryReg{2} = binaryReg{2}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                binaryReg{3} = app.BinaryImage.*sector_masks(:,:,3); %SN
                binaryReg{3} = binaryReg{3}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                binaryReg{4} = app.BinaryImage.*sector_masks(:,:,4); %NS
                binaryReg{4} = binaryReg{4}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                binaryReg{5} = app.BinaryImage.*sector_masks(:,:,5); %NI
                binaryReg{5} = binaryReg{5}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                binaryReg{6} = app.BinaryImage.*sector_masks(:,:,6); %IN
                binaryReg{6} = binaryReg{6}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                binaryReg{7} = app.BinaryImage.*sector_masks(:,:,7); %IT
                binaryReg{7} = binaryReg{7}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                binaryReg{8} = app.BinaryImage.*sector_masks(:,:,8); % TI
                binaryReg{8} = binaryReg{8}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                binaryReg{9} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)+sector_masks(:,:,4)); %S_hemi
                binaryReg{9} = binaryReg{9}(1:round(app.fullImSizePix(1)/2),:);
                binaryReg{10} = app.BinaryImage.*(sector_masks(:,:,5)+sector_masks(:,:,6)+sector_masks(:,:,7)+sector_masks(:,:,8)); %I_Hemi
                binaryReg{10} = binaryReg{10}(round(app.fullImSizePix(1)/2):end,:);
                binaryReg{11} = app.BinaryImage.*(sector_masks(:,:,2)+sector_masks(:,:,3)); %S
                binaryReg{11} = binaryReg{11}(1:round(app.fullImSizePix(1)/2),:);
                binaryReg{12} = app.BinaryImage.*(sector_masks(:,:,6)+sector_masks(:,:,7)); %I
                binaryReg{12} = binaryReg{12}(round(app.fullImSizePix(1)/2):end,:);
                binaryReg{13} = app.BinaryImage.*(sector_masks(:,:,4)+sector_masks(:,:,5)); %N
                binaryReg{13} = binaryReg{13}(:,1:round(app.fullImSizePix(1)/2));
                binaryReg{14} = app.BinaryImage.*(sector_masks(:,:,1)+sector_masks(:,:,8)); %T
                binaryReg{14} = binaryReg{14}(:,round(app.fullImSizePix(1)/2):end);
                binaryReg{15} = app.BinaryImage.*PP_mask; %PP
                

                imgReg{1} = app.inputImage.*sector_masks(:,:,1);
                imgReg{1} = imgReg{1}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                imgReg{2} = app.inputImage.*sector_masks(:,:,2);
                imgReg{2} = imgReg{2}(1:round(app.fullImSizePix(1)/2),round(app.fullImSizePix(1)/2):end);
                imgReg{3} = app.inputImage.*sector_masks(:,:,3);
                imgReg{3} = imgReg{3}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                imgReg{4} = app.inputImage.*sector_masks(:,:,4);
                imgReg{4} = imgReg{4}(1:round(app.fullImSizePix(1)/2),1:round(app.fullImSizePix(1)/2));
                imgReg{5} = app.inputImage.*sector_masks(:,:,5);
                imgReg{5} = imgReg{5}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                imgReg{6} = app.inputImage.*sector_masks(:,:,6);
                imgReg{6} = imgReg{6}(round(app.fullImSizePix(1)/2):end,1:round(app.fullImSizePix(1)/2));
                imgReg{7} = app.inputImage.*sector_masks(:,:,7);
                imgReg{7} = imgReg{7}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                imgReg{8} = app.inputImage.*sector_masks(:,:,8);
                imgReg{8} = imgReg{8}(round(app.fullImSizePix(1)/2):end,round(app.fullImSizePix(1)/2):end);
                imgReg{9} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)+sector_masks(:,:,4));
                imgReg{9} = imgReg{9}(1:round(app.fullImSizePix(1)/2),:);
                imgReg{10} = app.inputImage.*(sector_masks(:,:,5)+sector_masks(:,:,6)+sector_masks(:,:,7)+sector_masks(:,:,8));
                imgReg{10} = imgReg{10}(round(app.fullImSizePix(1)/2):end,:);
                imgReg{11} = app.inputImage.*(sector_masks(:,:,2)+sector_masks(:,:,3));
                imgReg{11} = imgReg{11}(1:round(app.fullImSizePix(1)/2),:);
                imgReg{12} = app.inputImage.*(sector_masks(:,:,6)+sector_masks(:,:,7));
                imgReg{12} = imgReg{12}(round(app.fullImSizePix(1)/2):end,:);
                imgReg{13} = app.inputImage.*(sector_masks(:,:,4)+sector_masks(:,:,5));
                imgReg{13} = imgReg{13}(:,1:round(app.fullImSizePix(1)/2));
                imgReg{14} = app.inputImage.*(sector_masks(:,:,1)+sector_masks(:,:,8));
                imgReg{14} = imgReg{14}(:,round(app.fullImSizePix(1)/2):end);
                imgReg{15} = app.inputImage.*PP_mask;


                if strcmp(app.RL.Value,'Right Eye')
                    app.RAlabels = ["NS" "SN" "ST" "TS" "TI" "IT" "IN" "NI" "S_hemi" "I_Hemi" "S" "I" "T" "N" "PP"];
                else
                    app.RAlabels = ["TS" "ST" "SN" "NS" "NI" "IN" "IT" "TI" "S_hemi" "I_Hemi" "S" "I" "N" "T" "PP"];
                end

                imgArea_pix_vec = [sum(sector_masks(:,:,1),'all') sum(sector_masks(:,:,2),'all') sum(sector_masks(:,:,3),'all') sum(sector_masks(:,:,4),'all') sum(sector_masks(:,:,5),'all')...
                    sum(sector_masks(:,:,6),'all') sum(sector_masks(:,:,7),'all') sum(sector_masks(:,:,8),'all') sum(sector_masks(:,:,1)+sector_masks(:,:,2)+sector_masks(:,:,3)+sector_masks(:,:,4),'all')...
                    sum(sector_masks(:,:,5)+sector_masks(:,:,6)+sector_masks(:,:,7)+sector_masks(:,:,8),'all') sum(sector_masks(:,:,2)+sector_masks(:,:,3),'all') sum(sector_masks(:,:,6)+sector_masks(:,:,7),'all')...
                    sum(sector_masks(:,:,4)+sector_masks(:,:,5),'all') sum(sector_masks(:,:,1)+sector_masks(:,:,8),'all') sum(PP_mask,'all')];
               
                grid_type = 'ONH'; %This will need to be updated to append the outer ring diameter 


            else %Foveal rings 
                %Calculate each ring radius in pixels
                rad_1mm = .5*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                rad_2mm = 1*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                rad_3mm = 1.5*app.fullImSizePix(1)/app.RealImageSizemm.Value;
                
                fig = figure;
                image(app.inputImage)
                fig.Visible = 'off';
                colormap gray
                axis equal
                xlim([0 app.fullImSizePix(1)])
                ylim([0 app.fullImSizePix(2)])
                hold on
                if isempty(app.FAZ_ctr)
                    app.FAZ_ctr = app.fullImSizePix/2;
                end
                
                roi_1mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_1mm,"LineWidth",2,"Color",'y');
                roi_2mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_2mm,"LineWidth",2,"Color",'y');
                roi_3mm = images.roi.Circle(gca,"Center",app.FAZ_ctr,"Radius",rad_3mm,"LineWidth",2,"Color",'y');

                foveal_mask = roi_1mm.createMask;
                pf500_mask = roi_2mm.createMask-roi_1mm.createMask;
                pfouter_mask = roi_3mm.createMask-roi_2mm.createMask;

                binaryReg{1} = app.BinaryImage.*foveal_mask; %foveal circle
                binaryReg{1} = binaryReg{1}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                binaryReg{2} = app.BinaryImage.*pf500_mask; % 0.5 mm ring around FAZ 
                binaryReg{2} = binaryReg{2}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                binaryReg{3} = app.BinaryImage.*pfouter_mask; %Outer 0.5 mm ring of 3mm circle around FAZ center 

                imgReg{1} = app.inputImage.*foveal_mask; %foveal circle
                imgReg{1} = imgReg{1}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                imgReg{2} = app.inputImage.*pf500_mask; % 0.5 mm ring around FAZ 
                imgReg{2} = imgReg{2}(round(max(app.FAZ_ctr(1)-rad_3mm,1)):round(min(app.FAZ_ctr(1)+rad_3mm,app.fullImSizePix(1))), round(max(app.FAZ_ctr(2)-rad_3mm,1)):round(min(app.FAZ_ctr(2)+rad_3mm,app.fullImSizePix(2))));
                imgReg{3} = app.inputImage.*pfouter_mask; %Outer 0.5 mm ring of 3mm circle around FAZ center 

                app.RAlabels = ["FAZ circle" "Inner 0.5mm ring around FAZ" "Outer 0.5mm ring"];
                
                imgArea_pix_vec = [sum(foveal_mask,'all') sum(pf500_mask,'all') sum(pfouter_mask ,'all') ];
            
            end


            %create the folder for validation
            validationFolder = fullfile(app.SaveFolder, 'regional_validation');
            if ~exist(validationFolder, 'dir')
                mkdir(validationFolder);
            end

            
            %Run metrics generation for each of the regions seperately
            for i = 1:size(binaryReg,2)
                skeletonize(app, imgReg{i}, binaryReg{i}, fol, 0);
                
                % --- Generate region name (safe filename) ---
                regionName = regexprep(app.RAlabels(i), '\s+', '_');  % remove spaces
                regionName = char(regionName);
            
                % --- Save grayscale region ---
                imwrite(uint8(imgReg{i}), ...
                    fullfile(validationFolder, sprintf('%s_%s_R%02d_%s_img.png', ...
                    app.FileLabelName, grid_type, i, regionName)));
            
                % --- Save binary region ---
                imwrite(uint8(binaryReg{i}), ...
                    fullfile(validationFolder, sprintf('%s_%s_R%02d_%s_binary.png', ...
                    app.FileLabelName, grid_type, i, regionName)));
            
                % --- Save skeleton result ---
                imwrite(uint8(app.imgSkel)*255, ...
                    fullfile(validationFolder, sprintf('%s_%s_R%02d_%s_skel.png', ...
                    app.FileLabelName, grid_type, i, regionName)));
            
                % --- OPTIONAL: Save all arrays in one MAT file ---
                imgRegion   = imgReg{i};
                binaryRegion = binaryReg{i};
                skelRegion   = app.imgSkel;
            
                save(fullfile(validationFolder, ...
                    sprintf('%s_%s_R%02d_%s_data.mat', ...
                    app.FileLabelName, grid_type, i, regionName)), ...
                    'imgRegion', 'binaryRegion', 'skelRegion');


                if ~strcmp(grid_type,'Square grid')
                    imgArea_pix = imgArea_pix_vec(i);
                    imgArea_mm = imgArea_pix *pix_to_mm^2;
                end
                regionalmetrics(i).Region = app.RAlabels(i);
                
                
                if ~isempty(app.Glinks)
                    regionalmetrics(i).nodes = sum(~[app.Gnodes.ep]);
                    regionalmetrics(i).VAD = round(sum(sum(binaryReg{i}))/255/imgArea_pix,4)*100;
                    regionalmetrics(i).totalLength = round(sum([app.Glinks.pixLength]*pix_to_mm),2); %In mm
                    regionalmetrics(i).VLD_pct = round(regionalmetrics(i).totalLength*pix_to_mm/imgArea_mm,4)*100; %in percent
                    regionalmetrics(i).VLD_mminv = round(regionalmetrics(i).totalLength/imgArea_mm,4); %in inverse mm
                    regionalmetrics(i).MeanLength = round(mean([app.Glinks.pixLength]*pix_to_mm*1000)); %in microns
                    regionalmetrics(i).MedianLength = round(median([app.Glinks.pixLength]*pix_to_mm*1000)); %in microns
                    regionalmetrics(i).MeanDiam = round(mean([app.Glinks.diameter]*pix_to_mm*1000)); %In microns
                    regionalmetrics(i).MedianDiam = round(median([app.Glinks.diameter]*pix_to_mm*1000));
                    regionalmetrics(i).BD = round(regionalmetrics(i).nodes./regionalmetrics(i).totalLength,2); %in 1/mm
                    slope = fractalDimension(app);
                    regionalmetrics(i).FD = round(slope,4);
                    tortuosity = [app.Glinks.tortuosity];
                    regionalmetrics(i).mTort = round(mean(tortuosity(~isinf(tortuosity))),4);
                else
                    regionalmetrics(i).nodes = 0;
                    regionalmetrics(i).VAD = round(sum(sum(binaryReg{i}))/255/imgArea_pix,4)*100;
                    regionalmetrics(i).totalLength = 0;
                    regionalmetrics(i).VLD_pct = 0;
                    regionalmetrics(i).VLD_mminv = 0;
                    regionalmetrics(i).MeanLength = 0;
                    regionalmetrics(i).MedianLength = 0;
                    regionalmetrics(i).MeanDiam = 0;
                    regionalmetrics(i).MedianDiam = 0;
                    regionalmetrics(i).BD = 0;
                    slope = fractalDimension(app);
                    regionalmetrics(i).FD = round(slope,4);
                    regionalmetrics(i).mTort = 0;
                end


            end
            
            %Save Excel file with results summary
            datatable = struct2table(regionalmetrics);
            ExcelFilename = fullfile(app.SaveFolder, [app.FileLabelName '_' app.datanum' '.xlsx']);
            writetable(datatable,ExcelFilename, 'Sheet', ['Regional analysis_' grid_type]);
            
        end
        
        function rapidFAZ(app)
            fval = app.RapidFAZstringencyfactorSlider.Value;
            [r,c] = size(app.BinaryImage);
            
            if fval==0
                sz = mean(r,c);
                D = ceil(sz*0.0075);
            else
                F = app.RapidFAZstringencyfactorSlider.Value;
                sz = mean(r,c);
                D = ceil(sz*F);
            end
            
            dilIm = imdilate(app.BinaryImage,offsetstrel('ball',D,ceil(D/2)));
            gdIm = imgaussfilt(dilIm,D/2);
            th = gdIm<192;
            objs = regionprops('table',th,'Area','BoundingBox','FilledImage','Centroid');
            [a,b] = size(objs);
            if a == 1
                faz_obj = objs;
                bb=uint16(faz_obj.BoundingBox);
                bbox = double(faz_obj.FilledImage{1});
            elseif a > 1
                objs.CentroidDists = pdist2(objs.Centroid,app.FAZ_ctr,'euclidean');
                var = min(objs.CentroidDists);
                faz_obj = objs(objs.CentroidDists==var,:);
                %var = max(objs.Area);
                %faz_obj = objs(objs.Area==var,:);
                bb=uint16(faz_obj.BoundingBox);
                bbox = double(faz_obj.FilledImage{1});
            else
                bb = [1,1,1,1];
                bbox=zeros(1,1);
            end
            faz = zeros(r,c);
            faz(bb(2):bb(2)+bb(4)-1,bb(1):bb(1)+bb(3)-1)=bbox;
            app.FAZmask=faz;

            app.FAZtype = 'Rapid';
        end
        
        function slope = fractalDimension(app)
            %Fractal dimension - box counting method
            img = logical(app.imgSkel); %This used to take the complement of the image, not sure why. 
            Nx = max(size(img));
            numIterations = ceil(log10(Nx)/log10(2));
            width = 2^numIterations;
            %pad img with zeros so that it is square with lengths = to a power of 2
            temp = zeros(width,width);
            temp(1:size(img,1), 1:size(img,2)) = img;
            img = temp;
            clear temp
            n_r = zeros(1,numIterations+1); %Initialize variable for counts
            
            %Perform box counting
            n_r(numIterations+1) = sum(sum(img));
            
            for ii = (numIterations-1):-1:0
                boxSize = 2^(numIterations-ii);
                boxRad = round(boxSize/2);
                
                for jj = 1:boxSize:(width-boxSize+1)
                    for kk = 1:boxSize:(width-boxSize+1)
                        img(jj,kk) = ( img(jj,kk) || img(jj+boxRad,kk) || img(jj,kk+boxRad) || img(jj+boxRad,kk+boxRad) );
                    end
                end
                
                n_r(ii+1) = sum(sum(img(1:boxSize:(width-boxSize+1),1:boxSize:(width-boxSize+1))));
            end
            
            n_r = n_r(end:-1:1);
            r = 2.^(0:numIterations);
            slope = -gradient(log10(n_r))./gradient(log10(r));
            slope1 = mean(slope);
            slope2 = median(slope);
            
            P = polyfit(log(r),-log(n_r),1);
            slope = P(1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off')  %Suppresses warning output
            app.MatlabVer = verLessThan('matlab', 'R2022a');
            %Establish path to app for loading dependency files
            appdir = mfilename('fullpath');
            inds = strfind(appdir,'\')';
            
            if isempty(inds)
                %This should help with mac compatability
                inds = strfind(appdir,'/')';
            end
            app.homedir = appdir(1:inds(end));
            app.lastPath = app.homedir;
            
            %load dpendency stripts
            scriptPath = fullfile(app.homedir,'Scripts');
            addpath(genpath(scriptPath));
            load 'bin overlay colormaps.mat' bin_overylay
            app.bin_overlay = bin_overylay;
            
            %Initialize save path to home directory
            app.SaveDir = app.homedir;
            app.savefileLabel_single.Text = app.SaveDir;
            
            %Initialize dataset counter and setup to record all metrics
            %from session
            init_session(app)
            
            %Initialize variables for seting checks
            app.skelReady = 0;
            
            
            warning('on')
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            app.ReadyLabel.Text = 'Wait';
            app.ReadyLamp.Color = [1.00,0.00,0.00];
            drawnow
            
            [Filename, Pathname] = uigetfile(fullfile(app.lastPath,'*.*'), 'Select MIP');
            
            if Filename == 0
                warndlg('You did not choose an image file') ;
                app.inputImage = [];
            else
                
                loadImage(app,Pathname, Filename)
            end
            
            app.datanum = '0'; %Initialize value
            
            
            %Display input image on front panel
            imshow( app.inputImage, 'Parent', app.inputImageAxes);
            caxis(app.inputImageAxes, [0 255]);
            app.FileLabel.Text = app.FileLabelName;

            %Set max values for kernel size for median filter, frangi
            %filter, and adaptive thresholding to the image size 
            app.kernelSize.Limits = [0 app.fullImSizePix(1)]; 
            app.sigmamaxSpinner.Limits = [0 app.fullImSizePix(1)]; 
            app.ATkernelsizeSpinner.Limits = [0 app.fullImSizePix(1)];

            %Setup folder for saving results.
            fol = fullfile(app.SaveFolder,'images');
            if ~exist(fol, 'dir')
                mkdir(fol)
            end
            
            %Find image number for saving binary mask 
            list = dir(fullfile(fol, '*_Binary mask*.tif'));
            app.binNum = length(list);

            %Clear previous binary mask
            clear app.BinaryImage
            app.skelReady = 0;
            
            
            app.inputImageAxes.Colormap = gray;
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0.00,1.00,0.00];
        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            if isempty(app.inputImage)
                warndlg('You did not choose an image file') ;
                cla(app.BinaryImageAxes);
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                %Run binarization function
                binarizeImage(app);
                
                %Display on front panel binary mask overlaid on original
                %image
                
                imshow( app.inputImage*3/4+10, 'Parent', app.BinaryImageAxes);
                hold(app.BinaryImageAxes,'on');
                
                t = app.BinaryImage;
                t(t==0) = nan;
                imagesc(t, 'Parent', app.BinaryImageAxes, 'AlphaData', .5*~isnan(t));
                
                app.BinaryImageAxes.Colormap = app.bin_overlay;
                caxis(app.BinaryImageAxes, [0 255]);
                hold(app.BinaryImageAxes,'off');
                
                app.datanum = datestr(datetime);
                app.datanum = erase(app.datanum,':')';

                %Setup folder for saving results.
                fol = fullfile(app.SaveFolder,'images');
                if ~exist(fol, 'dir')
                    mkdir(fol)
                end

                %Save binary mask and binary overlay 
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary mask_' num2str(app.binNum) '.tif']);
                imwrite(app.BinaryImage,TifFileName,'tif','WriteMode','append','Compression','none','Description','none');
                
                %Save the overlay image
                fig = figure;
                fig.Visible = 'off';
                figAxes = axes(fig);
                allChildren = app.BinaryImageAxes.XAxis.Parent.Children;
                copyobj(allChildren, figAxes)
                fig.Colormap = app.bin_overlay;
                figAxes.XLim = app.BinaryImageAxes.XLim;
                figAxes.YLim = app.BinaryImageAxes.YLim;
                figAxes.ZLim = app.BinaryImageAxes.ZLim;
                caxis(figAxes, [0 255])
                figAxes.DataAspectRatio = app.BinaryImageAxes.DataAspectRatio;
                figAxes.YDir = 'reverse';
                axis(figAxes,'off')
                fig.Position = fig.Position.*[1 1 5 5];
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary overlay_' num2str(app.binNum) '.tif']);
                export_fig(fig, TifFileName);
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
                app.skelReady = 1;
            end
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            %Inverts the colormap of the binary mask
            app.BinaryImage(app.BinaryImage == 0) = 1;
            app.BinaryImage(app.BinaryImage == 255) = 0;
            app.BinaryImage(app.BinaryImage == 1) = 255;
            
            
            imshow( app.inputImage*3/4+10, 'Parent', app.BinaryImageAxes);
            hold(app.BinaryImageAxes,'on');
            
            t = app.BinaryImage;
            t(t==0) = nan;
            imagesc(t, 'Parent', app.BinaryImageAxes, 'AlphaData', .5*~isnan(t));
            
            app.BinaryImageAxes.Colormap = app.bin_overlay;
            caxis(app.BinaryImageAxes, [0 255]);
            hold(app.BinaryImageAxes,'off');
            
        end

        % Button pushed function: SkeletonizeButton
        function SkeletonizeButtonPushed(app, event)
            if app.PrebinaryCheckBox.Value && length(unique(app.inputImage))==2
                %If the image is prebinarized 
                app.skelReady = 1;
                app.BinaryImage = app.inputImage;
                
            end
            
            
            if ~app.skelReady
                if isempty(app.inputImage)
                    warndlg('You did not choose an image file') ;
                else
                    warndlg('Binary mask required for skeletonization') ;
                end
            else
                app.ReadyLabel.Text = 'Wait'; %sets wait label
                app.ReadyLamp.Color = [1,0,0];
                drawnow
                
                %Setup folder for saving results.
                fol = fullfile(app.SaveFolder,'images');
                if ~exist(fol, 'dir')
                    mkdir(fol)
                end


                [sk2, ovrlyFileName] = skeletonize(app, app.inputImage, app.BinaryImage, fol, 1);
                
                %display skeleton overlaid on original image on front panel
                imshow( app.inputImage*3/4+10, 'Parent', app.SkelImageAxes);
                hold(app.SkelImageAxes,'on');
                app.SkelImageAxes.Colormap = app.bin_overlay;
                caxis(app.SkelImageAxes, [0 255]);
                imagesc(sk2*255, 'Parent', app.SkelImageAxes, 'AlphaData', .5);
                hold(app.SkelImageAxes,'off');
                
                
                %Load tif of overlay image and display in front panel
                %This is done to help save memeory
                if app.DisplayoverlayCheckBox.Value
                    ovrly = imread(ovrlyFileName);
                    imshow(ovrly, 'Parent', app.OverlayImageAxes);
                end
                
                generateMetrics(app)
                
                
                %Display results in window on front panel - caluclulated metrics
                app.VesselareadensityLabel.Text = ['Vessel area density = ' num2str(app.metrics.VAD) '%'];
                app.VessellengthdensityLabel.Text = ['Vessel length density = ' num2str(app.metrics.VLD_mminv) '%'];
                app.TotalvessellengthLabel.Text = ['Total vessel length = ' num2str(app.metrics.totalLength) ' mm'];
                app.MeandiameterLabel.Text = ['Mean diameter = ' num2str(app.metrics.MeanDiam) ' ' char(181) 'm'];
                app.MediandiameterLabel.Text = ['Median diameter = ' num2str(app.metrics.MedianDiam) ' ' char(181) 'm'];
                app.BranchpointdensityLabel.Text = ['Branchpoint density = ' num2str(app.metrics.BD) ' (1/mm)'];
                app.FractaldimensionLabel.Text = ['Fractal dimension = ' num2str(app.metrics.FD)];
                app.MeantortuosityLabel.Text = ['Mean tortuosity = ' num2str(app.metrics.MeanTortuosity)];
                
                %Histograms
                histogram(app.metrics.Diams,'BinWidth',app.binsizeDiams.Value,'Parent',app.DiamsAxes);
                histogram(app.metrics.Tortuosity,'BinWidth',app.binsizeTort.Value,'Parent', app.TortsAxes);
                histogram(app.metrics.Lengths,'BinWidth',app.binsizeLength.Value,'Parent', app.LengthsAxes);
                app.TortsAxes.XLim = [1 max(app.metrics.Tortuosity)];


                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0 1 0];
                drawnow
                app.skelReady = 3;
                
                
            end
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            if isempty(app.inputImage)
                warndlg('You did not choose an image file') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                prompt = {'x pixels:', 'y pixels'};
                dlgtitle = 'New image size';
                [Ny,Nx] = size(app.inputImage);
                definput = {num2str(Nx), num2str(Ny)};
                newsize = inputdlg(prompt,dlgtitle,[1 35],definput);
                if ~isempty(newsize)
                    app.inputImage = imresize(app.inputImage,[str2double(newsize{2}), str2double(newsize{1})]);
                    imagesc( app.inputImage, 'Parent', app.inputImageAxes);
                end
                app.fullImSizePix = size(app.inputImage); %Changing the size changes the pix/mm
                app.FAZ_ctr = app.fullImSizePix/2;

                %Set max values for kernel size for median filter, frangi
                %filter, and adaptive thresholding to the image size 
                app.kernelSize.Limits = [0 app.fullImSizePix(1)]; 
                app.sigmamaxSpinner.Limits = [0 app.fullImSizePix(1)]; 
                app.ATkernelsizeSpinner.Limits = [0 app.fullImSizePix(1)];


                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
            end
        end

        % Button pushed function: ImagesubsetButton
        function ImagesubsetButtonPushed(app, event)
            if isempty(app.inputImage)
                warndlg('You did not choose an image file') ;
            else
                ne = newline;
                fid = uifigure('Name','Select subregion of interest','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                imaxs = uiaxes("Parent",fid,'Position',[120,20,400,400]);
                imagesc(app.inputImage, 'Parent',imaxs)
                imaxs.Colormap = gray;
                [Ny,Nx] = size(app.inputImage);
                imaxs.XLim = [0 Nx];
                imaxs.YLim = [0 Ny];
                imaxs.Title.String = 'Please press start to select ROI';
                imaxs.Title.FontSize = 16;
                donebutton = uibutton(fid,"push",'Text','Done','Position',[30 60 60 20]);
                cancelbutton = uibutton(fid,"push",'Text','Cancel','Position',[30 10 60 20]);
                roiSizebutton = uibutton(fid,"push",'Text','Get ROI size', 'Position', [20 135 80 20]);
                startbutton = uibutton(fid,"push",'Text','Start','Position',[30 295 60 20]);
                clearbutton = uibutton(fid,"push",'Text','Clear','Position',[30 35 60 20]);
                sizelabel = uilabel(fid,'Position',[20,160,80,40],"Text",'ROI size',"BackgroundColor",[1 1 1]);
                roiTypGrp = uibuttongroup(fid, 'Position', [20 320  80 60 ]);
                roi1 = uiradiobutton(roiTypGrp,"Text",'Rectangle','Position',[5 30 80 20]);
                uiradiobutton(roiTypGrp,"Text",'Round','Position',[5 10 60 20]);
                resizeCheckBox = uicheckbox(fid,"Text",['Use ROI size' ne 'as image size?'],"Position",[15 175 100 80]);
                realSizeField = uieditfield(fid, 'numeric',"Position",[20,245,80,20],"Value",5);
                uilabel(fid,'Position',[20,253,100,40],"Text",'Img width (mm)');
                pixmmswitch = uiswitch(fid, 'slider',"Position",[40 110 80 20],"Items",{'pix','mm'});
                
                
                roiTypGrp.SelectionChangedFcn = {@roiselection,roi1};
                startbutton.ButtonPushedFcn = {@startButtonPushed};
                donebutton.ButtonPushedFcn = {@doneButtonPushed,fid,app,resizeCheckBox};
                cancelbutton.ButtonPushedFcn = {@cancelcallback, fid};
                roiSizebutton.ButtonPushedFcn = {@roiSizeCallback,fid,sizelabel,realSizeField,pixmmswitch,Nx};
                clearbutton.ButtonPushedFcn = {@clearButtonPushed};
                
                
                roishape = 'rect';
                h_rect = [];
                
            end
            
            function cancelcallback(~,~,fid)
                close(fid)
            end


            function app = doneButtonPushed(~,~,fid,app,resizeCheckBox)
                if strcmp(roishape,'rect')
                    pos_rect = round(h_rect.Position);
                    pos_rect(pos_rect<1) =1;
                    [y,~] = size(app.inputImage);
                    pos_rect(pos_rect>(y)) =y;
                    app.inputImage = app.inputImage(pos_rect(2) + (0:pos_rect(4)), pos_rect(1) + (0:pos_rect(3)));
                    if resizeCheckBox.Value
                        app.fullImSizePix = pos_rect(3);
                    end
                    app.FAZ_ctr = [pos_rect(3)/2,pos_rect(4)/2];
                    %Set max values for kernel size for median filter, frangi
                    %filter, and adaptive thresholding to the image size 
                    app.kernelSize.Limits = [0 app.fullImSizePix(1)]; 
                    app.sigmamaxSpinner.Limits = [0 app.fullImSizePix(1)]; 
                    app.ATkernelsizeSpinner.Limits = [0 app.fullImSizePix(1)];
                else
                    t = h_rect.createMask;
                    app.inputImage = app.inputImage.*t;
                    Cx = round(h_rect.Center(1));
                    Cy = round(h_rect.Center(2));
                    r = round(h_rect.Radius);
                    app.inputImage = app.inputImage(Cy-r:Cy+r,Cx-r:Cx+r);
                    if resizeCheckBox.Value
                        app.fullImSizePix = h_rect.Radius*2;
                    end
                    app.FAZ_ctr = [Cx,Cy];
                    %Set max values for kernel size for median filter, frangi
                    %filter, and adaptive thresholding to the image size 
                    app.kernelSize.Limits = [0 app.fullImSizePix(1)]; 
                    app.sigmamaxSpinner.Limits = [0 app.fullImSizePix(1)]; 
                    app.ATkernelsizeSpinner.Limits = [0 app.fullImSizePix(1)];
                end
                
                app.imgShape = roishape;
                
                close(fid)
                imagesc( app.inputImage, 'Parent', app.inputImageAxes);
            end
            
            function  roiselection(~,~,roi1)
                if roi1.Value
                    roishape = 'rect';
                else
                    roishape = 'round';
                end
                
            end
            
            function roiSizeCallback(~,~,~,sizelabel,realSizeField,pixmmswitch,Nx)
                if ~isempty(h_rect)
                    c = newline;
                    if strcmp(pixmmswitch.Value,'pix')
                        if strcmp(roishape,'rect')
                            pos_rect = round(h_rect.Position);
                            sizelabel.Text = ['x = ' num2str(pos_rect(3)) ' pixels'  c 'y = ' num2str(pos_rect(4)) ' pixels'];
                        else
                            sizelabel.Text = ['r = ' num2str(round(h_rect.Radius)) ' pixels'];
                        end
                    else
                        pix_to_mm = realSizeField.Value/Nx;
                        if strcmp(roishape,'rect')
                            pos_rect = round(h_rect.Position.*pix_to_mm,2);
                            sizelabel.Text = ['x = ' num2str(pos_rect(3)) ' mm'  c 'y = ' num2str(pos_rect(4)) ' mm'];
                        else
                            sizelabel.Text = ['r = ' num2str(round(h_rect.Radius*pix_to_mm,2)) ' mm'];
                        end
                    end
                    
                else
                    sizelabel.Text = 'ROI not found';
                end
            end
            
            function startButtonPushed(~,~)
                if strcmp(roishape,'rect')
                    h_rect = drawrectangle(imaxs);
                else
                    h_rect = drawcircle(imaxs);
                end
            end
            
            function clearButtonPushed(~,~)
                delete(h_rect)
                h_rect = [];
            end
        end

        % Menu selected function: saveBinaryImageMenu
        function saveBinaryImageMenuSelected(app, event)
            %Saves binary mask and overlay image
            app.ReadyLabel.Text = 'Wait'; %sets wait label
            app.ReadyLamp.Color = [1,0,0];
            drawnow
            
            fol = fullfile(app.SaveFolder,'images');
            if ~exist(fol, 'dir')
                mkdir(fol)
                imgnum = 0;
            else
                list = dir(fullfile(fol, '*_Binary mask*.tif'));
                imgnum = length(list);
            end
            
            if ~app.skelReady
                warndlg('No binary mask found') ;
            else
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary mask_' num2str(imgnum) '.tif']);
                imwrite(app.BinaryImage,TifFileName,'tif','WriteMode','append','Compression','none','Description','none');
                
                %Save the overlay image
                fig = figure;
                fig.Visible = 'off';
                figAxes = axes(fig);
                allChildren = app.BinaryImageAxes.XAxis.Parent.Children;
                copyobj(allChildren, figAxes)
                fig.Colormap = app.bin_overlay;
                figAxes.XLim = app.BinaryImageAxes.XLim;
                figAxes.YLim = app.BinaryImageAxes.YLim;
                figAxes.ZLim = app.BinaryImageAxes.ZLim;
                figAxes.DataAspectRatio = app.BinaryImageAxes.DataAspectRatio;
                caxis(figAxes, [0 255])
                figAxes.YDir = 'reverse';
                axis(figAxes,'off')
                fig.Position = fig.Position.*[1 1 5 5];
                TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary overlay_' num2str(imgnum) '.tif']);
                export_fig(fig, TifFileName);
                
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0 1 0];
                drawnow
                
            end
            
            
        end

        % Menu selected function: saveSessionDataMenu
        function saveSessionDataMenuSelected(app, event)
            savedir = uigetdir(app.lastPath);
            if savedir ~=0
                app.lastPath=savedir;
            end
            datatable = struct2table(app.metrics_cumulative);
            t = datestr(datetime);
            t = erase(t,':')';
            ExcelFilename = [savedir '/OCTAVA Results ' t' '.xlsx']; %This may have issues on mac?!
            writetable(datatable,ExcelFilename,'Sheet', 'General metrics');
            
            
            datatable = struct2table(app.FAZ_metrics_cumulative);
            writetable(datatable,ExcelFilename, 'Sheet', 'FAZ metrics');
        end

        % Menu selected function: ClearsessiondataMenu
        function ClearsessiondataMenuSelected(app, event)
            %Reinitializes variables for saving session results
            init_session(app)
        end

        % Menu selected function: ClearplotsMenu
        function ClearplotsMenuSelected(app, event)
            %Clear all plots
            cla(app.inputImageAxes);
            cla(app.BinaryImageAxes);
            cla(app.SkelImageAxes);
            cla(app.OverlayImageAxes);
            cla(app.DiamsAxes);
            cla(app.LengthsAxes);
            cla(app.TortsAxes);
            axis(app.OverlayImageAxes,'on')
            
            %Reset display for caluclulated biomarkers
            app.VesselareadensityLabel.Text = 'Vessel area density = ';
            app.VessellengthdensityLabel.Text = 'Vessel length density = ';
            app.TotalvessellengthLabel.Text = 'Total velles length = ';
            app.MeandiameterLabel.Text = 'Mean diameter = '  ;
            app.MediandiameterLabel.Text = 'Median diameter = ';
            app.BranchpointdensityLabel.Text = 'Branchpoint density = ';
            app.FractaldimensionLabel.Text = 'Fractal dimension = ';
            app.MeantortuosityLabel.Text = 'Mean tortuosity = ';
            
            %Reset ready label
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0 1 0];
        end

        % Menu selected function: ShowOCTAVAhelpMenu
        function ShowOCTAVAhelpMenuSelected(app, event)
            helpfile = fullfile(app.homedir,'OCTAVA2.0_Readme.txt');
            open(helpfile)
        end

        % Value changed function: RegionanalysisCheckBox
        function RegionanalysisCheckBoxValueChanged(app, event)
            if app.RegionanalysisCheckBox.Value
                init_session_retina(app)
            end
        end

        % Button pushed function: MeasureFAZButton
        function MeasureFAZButtonPushed(app, event)
            if isempty(app.BinaryImage)
                warndlg('Binary image required') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                
                FAZAnalysis(app);
                
            end
            
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0.00,1.00,0.00];
        end

        % Button pushed function: RegionalanalysisButton
        function RegionalanalysisButtonPushed(app, event)
            if isempty(app.BinaryImage)
                warndlg('Binary image required') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                
                regionalAnalysis(app);
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
            end
        end

        % Button pushed function: SelectSaveFolderButton_single
        function SelectSaveFolderButton_singlePushed(app, event)
            [app.SaveDir] = uigetdir(app.lastPath);

            class(app.savefileLabel_single)
            whos app.SaveFolder
            whos app.SaveFolder
            disp(string(app.SaveFolder))
            disp(size(string(app.SaveFolder)))
            app.savefileLabel_single.Text = string(app.SaveFolder);
            
            if ~exist(app.SaveDir, 'dir')
                mkdir(app.SaveDir)
            end
            
            app.SaveFolder = fullfile(app.SaveFolder,app.FileLabelName);
            if ~exist(app.SaveFolder, 'dir')
                mkdir(app.SaveFolder)
            end
            
            app.lastPath = app.SaveDir;
        end

        % Button pushed function: RawdatafolderButton
        function RawdatafolderButtonPushed(app, event)
            [app.RawFolder] = uigetdir(app.lastPath);
            if app.RawFolder == 0
                app.RawFolder = app.lastPath;
            end
            app.bachfileLabel.Text = app.RawFolder;
            
        end

        % Button pushed function: StartbatchprocessButton
        function StartbatchprocessButtonPushed(app, event)
               
            prebin = 0;
            if strcmp(app.ProcessFolderOrList.Value,'Process from folder')

                %Generate list of all files in the directory
                filetype = app.FiletypeDropDown.Value;             
                list = dir(fullfile(app.RawFolder,['*.' filetype]));
            
            else
                %Load list from excel file 
                [FILENAME, PATHNAME] = uigetfile('*.xlsx', 'Select Excel spreadsheet with list of filenames');
                xlxsfile = fullfile(PATHNAME,FILENAME); 
                list = readtable(xlxsfile);
                list = table2struct(list);
                     
            end

      
            if app.PrebinaryCheckBox.Value
                prebin = 1;
            end

            if ~isempty(list)
                ready = questdlg(['Are you sure you are ready to start batch processing ' num2str(length(list)) ' files?'] ,'Batch processing', 'Yes', 'No', 'No');
            else
                warndlg('No images selected') ;
                ready ='No'; 
            end

            
            if strcmp(ready,'Yes')
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                app.Abort.Value = false;
                app.Abort.FontColor = [1, 0, 0];
                drawnow
                
                
                app.SaveDir = app.savefileLabel_single.Text;


                %Setup variables for saving metrics
                init_session(app)
                
                if app.RegionanalysisCheckBox.Value
                    init_session_retina(app)

                    grid_list = {'Square grid', 'ETDRS grid','Extended ETDRS grid', 'Foveal rings', 'Optic nerve head'};
                    [grid_indx,~] = listdlg('ListString',grid_list, 'PromptString', {'Which regional analysis to run?', 'Multiple choices can be selected'});

                    nRegions = 0;
                    if ismember(1,grid_indx)
                        nRegions = nRegions+9;
                    end
                    if ismember(2,grid_indx)
                        nRegions = nRegions+8;
                    end
                    if ismember(3,grid_indx)
                        nRegions = nRegions+10;
                    end
                    if ismember(4,grid_indx)
                        nRegions = nRegions+3;
                    end    
                    if ismember(5,grid_indx)
                        nRegions = nRegions+15;
                    end  

                    grid_list = grid_list(grid_indx);

       
                    RegionalAnalysisCumulative = cell(1,nRegions);
                    RA_init_struct = struct('name', 'filename', 'Region','filename','nodes',0,'VAD',0,'totalLength',0, 'VLD_pct', 0, 'VLD_mminv', 0, 'MeanLength',0,'MedianLength',0,'MeanDiam',0,'MedianDiam',0,'BD',0,'mTort', 0, 'FD',0);
                    for ii = 1:nRegions
                        RegionalAnalysisCumulative{ii} = RA_init_struct;
                    end

                end


                %Timestamp for saving cumulative data
                t_stmp = datestr(datetime);
                t_stmp  = erase(t_stmp ,':');
                
                for nn = 1:length(list)
                    app.ProgressLabel.Text = ['Processing ' num2str(nn) ' of ' num2str(length(list))];
                    drawnow
                    
                    %Load each image
                    names   = fieldnames(list);
                    tName = strcmpi('name',names);
                    loadImage(app, app.RawFolder, list(nn).(names{tName}))
                    app.FileLabel.Text = app.FileLabelName;

                    if app.VariableimgsizeCheckBox.Value && ~strcmp(app.ProcessFolderOrList.Value,'Process from folder')
                                            
                        tName = strcmpi('size',names);
                        app.RealImageSizemm.Value = list(nn).(names{tName});
                    end

                    %If batch resize, then resize image 
                    if app.BatchresizeCheckBox.Value
                        app.inputImage = imresize(app.inputImage, [app.BatchResizeVal.Value app.BatchResizeVal.Value]);
                        app.fullImSizePix = size(app.inputImage); %Changing the size changes the pix/mm
                        app.FAZ_ctr = app.fullImSizePix/2;
                    end
                    
                    fol = fullfile(app.SaveFolder,'images');
                    if ~exist(fol, 'dir')
                        mkdir(fol)
                    end
                    
                    %Run the binarization
                    if prebin == 0
                        binarizeImage(app)
                    end
                    
                    pause(0.1)
                    if get(app.Abort, 'value')
                        break;
                    end
                    
                    if app.SavebinaryCheckBox.Value
                        list2 = dir(fullfile(fol, '*_Binary mask*.tif'));
                        imgnum = length(list2);
                        TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary mask_' num2str(imgnum) '.tif']);
                        imwrite(app.BinaryImage,TifFileName,'tif','WriteMode','append','Compression','none','Description','none');
                        
                        %Save the overlay image
                        fig = figure;
                        fig.Visible = 'off';
                        imagesc(app.inputImage*3/4+10);
                        axis equal
                        xlim([0 size(app.inputImage,1)])
                        hold on 
                        t = app.BinaryImage;
                        t(t==0) = nan;
                        imagesc(t, 'AlphaData', .5*~isnan(t));
                        fig.Colormap = app.bin_overlay;
                        caxis( [0 255]);
                        fig.Position = fig.Position.*[1 1 5 5];
                        TifFileName=fullfile(fol,[ app.FileLabelName  '_Binary overlay_' num2str(imgnum) '.tif']);
                        export_fig(fig, TifFileName);
                    end
                    
                    %skeletonization
                    
                    skeletonize(app, app.inputImage, app.BinaryImage, fol, 1);
                    
                    pause(0.1)
                    if get(app.Abort, 'value')
                        break;
                    end
                    
                    %Generate metrics
                    generateMetrics(app)

                     if app.SegmentFAZCheckBox.Value
                        rapidFAZ(app);
                        %app.TuneFAZsegmentationCheckBox.Value =  true; 
                        FAZAnalysis(app)
                    end
                        
                    pause(0.1)
                    if get(app.Abort, 'value')
                        break;
                    end

                    
                    if app.RegionanalysisCheckBox.Value
                        pause(0.1)
                        if get(app.Abort, 'value')
                            break;
                        end

                                             
                        Reg_ctr = 1;
                        for jj = 1:length(grid_list)
                            app.GridType.Value = grid_list(jj);
                            regionalmetrics = regionalAnalysis(app);

                            if strcmp(grid_list(jj),'Square grid')
                                nReg = 9;
                            elseif strcmp(grid_list(jj),'ETDRS grid')
                                nReg = 8;
                            elseif strcmp(grid_list(jj), 'Extended ETDRS grid')
                                nReg = 10;
                            elseif strcmp(grid_list(jj), 'Optic nerve head')
                                nReg = 15;
                            else
                                nReg = 3;
                            end 

                            for ii = Reg_ctr:Reg_ctr+nReg-1
                                regionalmetrics(ii-Reg_ctr+1).name = app.metrics.name; 
                                RegionalAnalysisCumulative{ii}(nn) = regionalmetrics(ii-Reg_ctr+1);
                            end
                            Reg_ctr = Reg_ctr+nReg;
                        end
                             
                        
                    end
                end
                
                if  ~isempty(list) && ~app.Abort.Value
                    %Save spreadsheet with summary metrics and FAZ metrics
                    datatable = struct2table(app.metrics_cumulative);
                    
                    ExcelFilename = [app.SaveDir '/OCTAVA Results ' t_stmp  '.xlsx']; %This may have issues on mac?!
                    writetable(datatable,ExcelFilename,'Sheet', 'General metrics');
                    
                    if app.RegionanalysisCheckBox.Value
                        datatable = struct2table(app.FAZ_metrics_cumulative);
                        writetable(datatable,ExcelFilename, 'Sheet', 'FAZ metrics');

                        for ii = 1:nRegions
                            region_name=RegionalAnalysisCumulative{ii}(1).Region; 
                            datatable = struct2table(RegionalAnalysisCumulative{ii});
                            writetable(datatable,ExcelFilename, 'Sheet', region_name);
                        end
                    end


                end
                
                init_session(app)
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
                app.Abort.Value = false;
                app.Abort.FontColor = [0.00,0.00,0.00];
                drawnow
                
                
            end
        end

        % Value changing function: RapidFAZstringencyfactorSlider
        function RapidFAZstringencyfactorSliderValueChanging(app, event)
            changingValue = event.Value;
            [r,c] = size(app.BinaryImage);
            sz = mean(r,c);
            D = ceil(sz*changingValue);
            app.Ffactor.Value=D;
            
        end

        % Value changed function: TuneFAZsegmentationCheckBox
        function TuneFAZsegmentationCheckBoxValueChanged(app, event)
            value = app.TuneFAZsegmentationCheckBox.Value;
            
        end

        % Button pushed function: RapidFAZButton
        function RapidFAZButtonPushed(app, event)
            if isempty(app.BinaryImage)
                warndlg('Binary image required') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                
                app.FAZtype = 'Rapid';
                rapidFAZ(app); 
                figure(1);
                imshowpair(app.BinaryImage,app.FAZmask);
                pause(3)
                close(1)
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
                drawnow
            end


        end

        % Button pushed function: PointsFAZButton
        function PointsFAZButtonPushed(app, event)
           
            if isempty(app.BinaryImage)
                warndlg('Binary image required') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                
                app.FAZtype = 'Draw';
                
                
                Q = 0;

                while Q == 0
                    %Setup figure window for manually selecting FAZ
    
                    I = app.BinaryImage; 
                    fid = uifigure('Name','Draw FAZ','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                    imaxs = uiaxes("Parent",fid,'Position',[90,5,400,400]);
                    imshow(I,"Parent",imaxs);
    
                    title(imaxs,'Click points to define a polygon around the FAZ'); 

                    %Let user draw on the polygon
                    p=drawpolygon(imaxs,'LineWidth',3,'Color','cyan');
                    [x,y]=meshgrid(1:size(I,2),1:size(I,1));
                    mask=inpolygon(x,y,p.Position(:,1),p.Position(:,2));
                    app.FAZmask = double(mask);
    
                    %Display segmentation as overlay
                    imshow(I,"Parent",imaxs);
                    hold(imaxs,'on');
                    t = app.FAZmask;
                    t(t==0) = nan;
                    imagesc(t, 'Parent', imaxs, 'AlphaData', .5*~isnan(t));

                    %Check if user is happy with polygon
                    answer = questdlg('Is segmentation sufficient?', ...
                    'Segmentation Confirmation',...
                    'Yes. Continue.','No. I want to try again.','No. I want to try again.');

                    switch answer
                        case 'Yes. Continue.'
                             
                            Q=1;
                            close(fid);
                        case 'No. I want to try again.'

                            close(fid);
                    end
    
                end

                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
                drawnow
            end


            % if isempty(app.BinaryImage)
           %      warndlg('Binary image required') ;
           %  else
           %      app.ReadyLabel.Text = 'Wait';
           %      app.ReadyLamp.Color = [1.00,0.00,0.00];
           %      drawnow
           % 
           %      app.FAZtype = 'Manual';
           % 
           %      %Setup figure window for manually selecting FAZ
           %      I = app.BinaryImage; 
           %      fid = uifigure('Name','find FAZ','NumberTitle','off',"Color",[0.55,0.75,0.88]);
           %      imaxs = uiaxes("Parent",fid,'Position',[120,20,400,400]);
           %      imshow(I,"Parent",imaxs);
           % 
           %       title(imaxs,'Select initial points, end by pressing "enter" on the key board'); 
           %       hold(imaxs, "on")
           %       fhv = fid.HandleVisibility;
           %       fid.HandleVisibility = 'callback';
           %       set(0, 'CurrentFigure', fid) 
           %       [x1,y1] = ginput;
           %       fid.HandleVisibility = fhv;
           %       hold(imaxs, "off")
           %       close(fid)
           % 
           %       % the levelset function
           %      [x,y] = meshgrid(1:size(I,2),1:size(I,1));
           %      m = inpolygon(x,y,x1,y1);
           %      app.FAZmask = double(m); 
           % 
           % 
           %      app.ReadyLabel.Text = 'Ready';
           %      app.ReadyLamp.Color = [0.00,1.00,0.00];
           %      drawnow
           %  end
            
        end

        % Button pushed function: FreeFAZButton
        function FreeFAZButtonPushed(app, event)
            if isempty(app.BinaryImage)
                warndlg('Binary image required') ;
            else
                app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                
                app.FAZtype = 'Draw';

                I = app.BinaryImage; 
                fid = uifigure('Name','Draw FAZ','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                imaxs = uiaxes("Parent",fid,'Position',[90,5,400,400]);
                startbutton = uibutton(fid,"push",'Text','Start','Position',[30 295 60 20]);
                title(imaxs,'Free-draw to define a polygon around the FAZ'); 
                imshow(I,"Parent",imaxs);

                Q = 0;

                startbutton.ButtonPushedFcn = {@startButtonPushed,app,Q,fid};
                uilabel(fid,'Position',[20,200,100,80],"Text",['Make sure to' newline 'de-select the' newline 'zoom tool' newline 'before clicking' newline 'start']);
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00];
                drawnow

                
             end

         function startButtonPushed(~,~,app,Q,fid)

             while Q == 0
                %Setup figure window for manually selecting FAZ   


                %Let user free-hand draw the mask 
                p = drawfreehand(imaxs);
                [x,y]=meshgrid(1:size(I,2),1:size(I,1));
                mask=inpolygon(x,y,p.Position(:,1),p.Position(:,2));
                app.FAZmask = double(mask);

                %Display segmentation as overlay
                imshow(I,"Parent",imaxs);
                hold(imaxs,'on');
                t = app.FAZmask;
                t(t==0) = nan;
                imagesc(t, 'Parent', imaxs, 'AlphaData', .5*~isnan(t));

                %Check if user is happy with polygon
                answer = questdlg('Is segmentation sufficient?', ...
                'Segmentation Confirmation',...
                'Yes. Continue.','No. I want to try again.','No. I want to try again.');

                switch answer
                    case 'Yes. Continue.'
                         
                        Q=1;
                        close(fid);
                    case 'No. I want to try again.'

                        %close(fid);
                end
            end
         end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OCTAVAUIFigure = uifigure('Visible', 'off');
            app.OCTAVAUIFigure.Name = 'OCTAVA';
            app.OCTAVAUIFigure.Icon = fullfile(pathToMLAPP, 'OCTAVAicon.png');
            app.SaveMenu = uimenu(app.OCTAVAUIFigure);
            app.SaveMenu.Text = 'Save';
            app.saveBinaryImageMenu = uimenu(app.SaveMenu);
            app.saveBinaryImageMenu.MenuSelectedFcn = createCallbackFcn(app, @saveBinaryImageMenuSelected, true);
            app.saveBinaryImageMenu.Text = 'Binary Image ';
            app.saveSessionDataMenu = uimenu(app.SaveMenu);
            app.saveSessionDataMenu.MenuSelectedFcn = createCallbackFcn(app, @saveSessionDataMenuSelected, true);
            app.saveSessionDataMenu.Text = 'Session Data';
            app.ClearMenu = uimenu(app.OCTAVAUIFigure);
            app.ClearMenu.Text = 'Clear';
            app.ClearsessiondataMenu = uimenu(app.ClearMenu);
            app.ClearsessiondataMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearsessiondataMenuSelected, true);
            app.ClearsessiondataMenu.Text = 'Clear session data';
            app.ClearplotsMenu = uimenu(app.ClearMenu);
            app.ClearplotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearplotsMenuSelected, true);
            app.ClearplotsMenu.Text = 'Clear plots';
            app.HelpMenu = uimenu(app.OCTAVAUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ShowOCTAVAhelpMenu = uimenu(app.HelpMenu);
            app.ShowOCTAVAhelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowOCTAVAhelpMenuSelected, true);
            app.ShowOCTAVAhelpMenu.Text = 'Show OCTAVA help';
            app.GridLayout = uigridlayout(app.OCTAVAUIFigure);
            app.TortsAxes = uiaxes(app.GridLayout);
            title(app.TortsAxes, 'Tortuosity')
            xlabel(app.TortsAxes, 'Tortuosity')
            ylabel(app.TortsAxes, 'Frequency')
            zlabel(app.TortsAxes, 'Z')
            app.TortsAxes.Tag = 'Torts';
            app.LengthsAxes = uiaxes(app.GridLayout);
            title(app.LengthsAxes, 'Length')
            xlabel(app.LengthsAxes, 'Length (mm)')
            ylabel(app.LengthsAxes, 'Frequency')
            zlabel(app.LengthsAxes, 'Z')
            app.LengthsAxes.Tag = 'Torts';
            app.DiamsAxes = uiaxes(app.GridLayout);
            title(app.DiamsAxes, 'Diameter')
            xlabel(app.DiamsAxes, 'Diameter (\mum)')
            ylabel(app.DiamsAxes, 'Frequency')
            zlabel(app.DiamsAxes, 'Z')
            app.DiamsAxes.Tag = 'Diams';
            app.OverlayImageAxes = uiaxes(app.GridLayout);
            title(app.OverlayImageAxes, 'Overlay')
            app.OverlayImageAxes.XTick = [];
            app.OverlayImageAxes.YTick = [];
            app.SkelImageAxes = uiaxes(app.GridLayout);
            title(app.SkelImageAxes, 'Skeletonized Image')
            app.SkelImageAxes.XTick = [];
            app.SkelImageAxes.YTick = [];
            app.BinaryImageAxes = uiaxes(app.GridLayout);
            title(app.BinaryImageAxes, 'Segmented Image ')
            app.BinaryImageAxes.XTick = [];
            app.BinaryImageAxes.YTick = [];
            app.inputImageAxes = uiaxes(app.GridLayout);
            title(app.inputImageAxes, 'Original Image')
            app.inputImageAxes.XTick = [];
            app.inputImageAxes.YTick = [];
            app.OCTAVALabel = uilabel(app.GridLayout);
            app.OCTAVALabel.Text = 'OCTAVA';
            app.FileLabel = uilabel(app.GridLayout);
            app.FileLabel.Text = 'Filename';
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.ReadyLamp = uilamp(app.GridLayout);
            app.LoadimageButton = uibutton(app.GridLayout, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ResizeButton = uibutton(app.GridLayout, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Re-size';
            app.BinarizeButton = uibutton(app.GridLayout, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            app.InvertButton = uibutton(app.GridLayout, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.SkeletonizeButton = uibutton(app.GridLayout, 'push');
            app.SkeletonizeButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonizeButtonPushed, true);
            app.SkeletonizeButton.Text = 'Skeletonize ';
            app.ImagesubsetButton = uibutton(app.GridLayout, 'push');
            app.ImagesubsetButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesubsetButtonPushed, true);
            app.ImagesubsetButton.Text = 'Image subset';
            app.SegmentationmethodDropDown = uidropdown(app.GridLayout);
            app.SegmentationmethodDropDown.Items = {'Fuzzy means', 'Adaptive thresholding'};
            app.SegmentationmethodDropDown.Value = 'Fuzzy means';
            app.MedfiltCheckBox = uicheckbox(app.GridLayout);
            app.MedfiltCheckBox.Text = 'Med filt';
            app.kernelSize = uispinner(app.GridLayout);
            app.kernelSize.Limits = [0 Inf];
            app.kernelSize.Value = 3;
            app.FrangiBool = uicheckbox(app.GridLayout);
            app.FrangiBool.Text = 'Frangi';
            app.FrangiBool.Value = true;
            app.sigmamaxSpinner = uispinner(app.GridLayout);
            app.sigmamaxSpinner.Limits = [0 30];
            app.sigmamaxSpinner.RoundFractionalValues = 'on';
            app.sigmamaxSpinner.ValueDisplayFormat = '%d';
            app.sigmamaxSpinner.Value = 8;
            app.ATkernelsizeSpinnerLabel = uilabel(app.GridLayout);
            app.ATkernelsizeSpinnerLabel.Text = {'AT'; 'kernel'; 'size'};
            app.ATkernelsizeSpinner = uispinner(app.GridLayout);
            app.ATkernelsizeSpinner.Step = 10;
            app.ATkernelsizeSpinner.Limits = [0 Inf];
            app.ATkernelsizeSpinner.Value = 70;
            app.TwigsizeLabel = uilabel(app.GridLayout);
            app.TwigsizeLabel.Text = 'Twig size ';
            app.TwigsizeSpinner = uispinner(app.GridLayout);
            app.TwigsizeSpinner.Limits = [0 30];
            app.TwigsizeSpinner.RoundFractionalValues = 'on';
            app.TwigsizeSpinner.ValueDisplayFormat = '%.0f';
            app.TwigsizeSpinner.Value = 20;
            app.SelectSaveFolderButton_single = uibutton(app.GridLayout, 'push');
            app.SelectSaveFolderButton_single.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveFolderButton_singlePushed, true);
            app.SelectSaveFolderButton_single.Text = 'Save folder';
            app.binsizeLength = uispinner(app.GridLayout);
            app.binsizeLength.Step = 0.1;
            app.binsizeLength.Limits = [0 Inf];
            app.binsizeLength.Value = 0.1;
            app.binsizeTort = uispinner(app.GridLayout);
            app.binsizeTort.Step = 0.1;
            app.binsizeTort.Limits = [0 Inf];
            app.binsizeTort.Value = 0.1;
            app.RealImageSizemm = uieditfield(app.GridLayout, 'numeric');
            app.RealImageSizemm.Value = 5;
            app.SegmentationmethodDropDownLabel = uilabel(app.GridLayout);
            app.SegmentationmethodDropDownLabel.Text = 'Segmentation method';
            app.ImagesizemmLabel = uilabel(app.GridLayout);
            app.ImagesizemmLabel.Text = {'Image '; 'size (mm)'};
            app.BinsizeforhistogramsLabel = uilabel(app.GridLayout);
            app.BinsizeforhistogramsLabel.Text = 'Bin size for histograms';
            app.binsizeDiams = uispinner(app.GridLayout);
            app.binsizeDiams.Limits = [0 Inf];
            app.binsizeDiams.Value = 10;
            app.RetinalAnalysisPanel = uipanel(app.GridLayout);
            app.RetinalAnalysisPanel.Title = 'Retinal Analysis ';
            app.GridLayout4 = uigridlayout(app.RetinalAnalysisPanel);
            app.RL = uidropdown(app.GridLayout4);
            app.RL.Items = {'Right Eye', 'Left Eye'};
            app.RL.Value = 'Right Eye';
            app.RegionalanalysisButton = uibutton(app.GridLayout4, 'push');
            app.RegionalanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RegionalanalysisButtonPushed, true);
            app.RegionalanalysisButton.Text = {'Regional'; 'analysis'};
            app.MeasureFAZButton = uibutton(app.GridLayout4, 'push');
            app.MeasureFAZButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureFAZButtonPushed, true);
            app.MeasureFAZButton.Text = 'Measure FAZ';
            app.GridType = uidropdown(app.GridLayout4);
            app.GridType.Items = {'Square grid', 'ETDRS grid', 'Extended ETDRS grid', 'Foveal rings', 'Optic nerve head'};
            app.GridType.Value = 'ETDRS grid';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.RapidFAZstringencyfactorSlider = uislider(app.GridLayout4);
            app.RapidFAZstringencyfactorSlider.Limits = [0.005 0.02];
            app.RapidFAZstringencyfactorSlider.MajorTicks = [0.005 0.0125 0.02];
            app.RapidFAZstringencyfactorSlider.MajorTickLabels = {'Lax', '', 'Strict'};
            app.RapidFAZstringencyfactorSlider.ValueChangingFcn = createCallbackFcn(app, @RapidFAZstringencyfactorSliderValueChanging, true);
            app.RapidFAZstringencyfactorSlider.MinorTicks = [0.005 0.0065 0.008 0.0095 0.011 0.0125 0.014 0.0155 0.017 0.0185 0.02];
            app.RapidFAZstringencyfactorSlider.Value = 0.0075;
            app.RapidFAZstringencyfactorSliderLabel = uilabel(app.GridLayout4);
            app.RapidFAZstringencyfactorSliderLabel.Text = {'Rapid FAZ '; 'stringency factor '};
            app.TuneFAZsegmentationCheckBox = uicheckbox(app.GridLayout4);
            app.TuneFAZsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @TuneFAZsegmentationCheckBoxValueChanged, true);
            app.TuneFAZsegmentationCheckBox.Text = 'Tune FAZ segmentation';
            app.RapidFAZButton = uibutton(app.GridLayout4, 'push');
            app.RapidFAZButton.ButtonPushedFcn = createCallbackFcn(app, @RapidFAZButtonPushed, true);
            app.RapidFAZButton.Text = {'Rapid '; 'FAZ'};
            app.PointsFAZButton = uibutton(app.GridLayout4, 'push');
            app.PointsFAZButton.ButtonPushedFcn = createCallbackFcn(app, @PointsFAZButtonPushed, true);
            app.PointsFAZButton.Text = {'Points'; 'FAZ'};
            app.FreeFAZButton = uibutton(app.GridLayout4, 'push');
            app.FreeFAZButton.ButtonPushedFcn = createCallbackFcn(app, @FreeFAZButtonPushed, true);
            app.FreeFAZButton.Text = {'Free'; 'FAZ'};
            app.MetricsPanel = uipanel(app.GridLayout);
            app.MetricsPanel.Title = 'Metrics';
            app.GridLayout3 = uigridlayout(app.MetricsPanel);
            app.VesselareadensityLabel = uilabel(app.GridLayout3);
            app.VesselareadensityLabel.Text = 'Vessel area density = ';
            app.VessellengthdensityLabel = uilabel(app.GridLayout3);
            app.VessellengthdensityLabel.Text = 'Vessel length density = ';
            app.MeandiameterLabel = uilabel(app.GridLayout3);
            app.MeandiameterLabel.Text = 'Mean diameter = ';
            app.MediandiameterLabel = uilabel(app.GridLayout3);
            app.MediandiameterLabel.Text = 'Median diameter = ';
            app.BranchpointdensityLabel = uilabel(app.GridLayout3);
            app.BranchpointdensityLabel.Text = 'Branchpoint density = ';
            app.TotalvessellengthLabel = uilabel(app.GridLayout3);
            app.TotalvessellengthLabel.Text = 'Total vessel length = ';
            app.FractaldimensionLabel = uilabel(app.GridLayout3);
            app.FractaldimensionLabel.Text = 'Fractal dimension = ';
            app.MeantortuosityLabel = uilabel(app.GridLayout3);
            app.MeantortuosityLabel.Text = 'Mean tortuosity = ';
            app.BatchProcessingPanel = uipanel(app.GridLayout);
            app.BatchProcessingPanel.Title = 'Batch Processing';
            app.GridLayout5 = uigridlayout(app.BatchProcessingPanel);
            app.RawdatafolderButton = uibutton(app.GridLayout5, 'push');
            app.RawdatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @RawdatafolderButtonPushed, true);
            app.RawdatafolderButton.Text = {'Raw data '; 'folder'};
            app.bachfileLabel = uilabel(app.GridLayout5);
            app.bachfileLabel.Text = 'Raw folder';
            app.FiletypeDropDown = uidropdown(app.GridLayout5);
            app.FiletypeDropDown.Items = {'tif', 'png', 'jpg', 'bmp', 'mat'};
            app.FiletypeDropDown.Value = 'tif';
            app.FiletypeDropDownLabel = uilabel(app.GridLayout5);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.SavebinaryCheckBox = uicheckbox(app.GridLayout5);
            app.SavebinaryCheckBox.Text = 'Save binary?';
            app.StartbatchprocessButton = uibutton(app.GridLayout5, 'push');
            app.StartbatchprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartbatchprocessButtonPushed, true);
            app.StartbatchprocessButton.Text = 'Start batch process';
            app.ProgressLabel = uilabel(app.GridLayout5);
            app.ProgressLabel.Text = 'Progress ';
            app.Abort = uibutton(app.GridLayout5, 'state');
            app.Abort.Text = 'Abort';
            app.BatchresizeCheckBox = uicheckbox(app.GridLayout5);
            app.BatchresizeCheckBox.Text = 'Batch resize';
            app.BatchResizeVal = uieditfield(app.GridLayout5, 'numeric');
            app.BatchResizeVal.Limits = [0 10000];
            app.BatchResizeVal.ValueDisplayFormat = '%.0f';
            app.BatchResizeVal.Value = 1000;
            app.ProcessFolderOrList = uidropdown(app.GridLayout5);
            app.ProcessFolderOrList.Items = {'Process from folder', 'Process from list '};
            app.ProcessFolderOrList.Value = 'Process from folder';
            app.RegionanalysisCheckBox = uicheckbox(app.GridLayout5);
            app.RegionanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegionanalysisCheckBoxValueChanged, true);
            app.RegionanalysisCheckBox.Text = 'Region analysis';
            app.RegionanalysisCheckBox.Value = true;
            app.VariableimgsizeCheckBox = uicheckbox(app.GridLayout5);
            app.VariableimgsizeCheckBox.Text = 'Variable img size';
            app.SegmentFAZCheckBox = uicheckbox(app.GridLayout5);
            app.SegmentFAZCheckBox.Text = 'Segment FAZ';
            app.SegmentFAZCheckBox.Value = true;
            app.savefileLabel_single = uilabel(app.GridLayout);
            app.DisplayoverlayCheckBox = uicheckbox(app.GridLayout);
            app.DisplayoverlayCheckBox.Text = {'Display'; 'overlay '};
            app.sigmaminSpinner = uispinner(app.GridLayout);
            app.sigmaminSpinner.Limits = [0 30];
            app.sigmaminSpinner.RoundFractionalValues = 'on';
            app.sigmaminSpinner.ValueDisplayFormat = '%d';
            app.sigmaminSpinner.Value = 1;
            app.MaxLabel = uilabel(app.GridLayout);
            app.MaxLabel.Text = 'Max';
            app.MinLabel = uilabel(app.GridLayout);
            app.MinLabel.Text = 'Min';
            app.PrebinaryCheckBox = uicheckbox(app.GridLayout);
            app.PrebinaryCheckBox.Text = 'Pre binary';
            % Show the figure after all components are created
            app.OCTAVAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OCTAVA_2p0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OCTAVAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OCTAVAUIFigure)
        end
    end
end